classdef ConfigMakerGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_config  matlab.ui.Figure
        text1          matlab.ui.control.Label
        table_noise    matlab.ui.control.Table
        text2          matlab.ui.control.Label
        text3          matlab.ui.control.Label
        edit_comDelay  matlab.ui.control.EditField
        push_load      matlab.ui.control.Button
        push_save      matlab.ui.control.Button
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
                       
            % Set component properties that require runtime configuration
            app.table_noise.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.table_noise.ColumnFormat = {'numeric' 'numeric'};
            app.table_noise.Data = zeros(7,2);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ConfigMakerGUI_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
        end

        % Value changed function: edit_comDelay
        function edit_comDelay_Callback(app, event)
     
            % Check that value is valid
            comDelay= str2double(app.edit_comDelay.Value); 
            if comDelay < 0 || isnan(comDelay)
                warning('MATLAB:invalidInput',...
                    'Communication delay parameter not valid')
                app.edit_comDelay.Value = '0'; 
            end
        end

        % Button pushed function: push_load
        function push_load_Callback(app, event)
                       
            % Import configuration file
            [filename, pathname, ~]= uigetfile('*.txt','Import Configuration File');
            if filename                         % Make sure cancel was not pressed
                fid= fopen([pathname filename]);% Get file handle for parsing
            
                % Initialize storage variable to no noise
                noise= zeros(7,2);
            
                % Parse the file and extract relevant information
                while ~feof(fid)
                    fline= fgetl(fid);      % Get line of file
                    line= lower(fline);     % Convert to lowercase if necessary
                    line= strtrim(line);    % Delete leading and trailing whitespace
                    lineWords= {};          % To keep track of line entries
                    while ~isempty(line) && ~strcmp(line(1),'%')% End of line or comment
                        [word, line]= strtok(line);  % Get next entry
                        line= strtrim(line);        % To be able to detect comments
                        lineWords= [lineWords word];
                    end
            
                    % Get information from line
                    sensors= {'wall' 'cliff' 'odometry' 'sonar' 'lidar' 'camera' 'rsdepth'};
                    % Sensors are in order as in the table
                    if length(lineWords) == 2 && strcmp(lineWords{1},'com_delay')
                        app.edit_comDelay.Value = lineWords{2}; 
                    elseif length(lineWords) == 3 && any(strcmp(lineWords{1},sensors))
                        noise(strcmp(lineWords{1},sensors),1:2)= ...
                            [str2double(lineWords{2}) str2double(lineWords{3})];
                    elseif ~isempty(lineWords)
                        warning('MATLAB:invalidInput',...
                            'This line in config file %s is unrecognized:\n\t%s',...
                            filename,fline)
                    end
                end
                fclose(fid);
            
                % Set noise data to the table
                app.table_noise.Data = noise; 
            end
        end

        % Button pushed function: push_save
        function push_save_Callback(app, event)
                     
            % Pull up dialogue box to get filename
            fileName= inputdlg('Filename (no extension):','Save Map Data');
            if ~isempty(fileName)
                fileName= [fileName{1} '.txt']; % Convert cell array and add extension
            
                % Check if file is already in existence
                button= 'Yes';
                if exist(fileName,'file')
                    button= questdlg('A file by that name exists.  Overwrite?',...
                        'Verify Overwrite','Yes','Cancel','Cancel');
                end
            
                if strcmp(button,'Yes')
                    % Get noise data from the table
                    noise= app.table_noise.Data;         % Matrix of doubles
                    comDelay= app.edit_comDelay.Value;   % String
            
                    % Deal with empty values
                    if isempty(comDelay) || isnan(str2double(comDelay))
                        comDelay= '0';
                    end
            
                    % Write configuration data to file
                    fid= fopen(fileName,'wt');
                    sensors= {'wall' 'cliff' 'odometry' 'sonar' 'lidar' 'camera' 'rsdepth'};
                    % Sensors are in order as in the table
                    fprintf(fid,'%% SensorName NoiseMean NoiseStandardDev\n');
                    for i= 1:size(noise,1)
                        fprintf(fid,'%s %.5f %.5f\n',sensors{i},noise(i,1),noise(i,2));
                    end
                    fprintf(fid,'\ncom_delay %s',comDelay);
                    fclose(fid);
                end
            end
        end

        % Cell edit callback: table_noise
        function table_noise_CellEditCallback(app, event)
                       
            % Warn and reset if invalid input (non-numeric)
            if isnan(event.NewData)
                warning('MATLAB:invalidInput',...
                    'Noise parameter not valid')
                noise= app.table_noise.Data; 
                noise(event.Indices(1),event.Indices(2))= 0;
                app.table_noise.Data = noise;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_config = uifigure('Visible', 'off');
            app.figure_config.Name = 'ConfigMakerGUI';
            app.figure_config.Tag = 'figure_config';
            app.text1 = uilabel(app.figure_config);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Configuration File Creator';
            app.table_noise = uitable(app.figure_config);
            app.table_noise.ColumnName = {'Mean'; 'St. Dev.'};
            app.table_noise.RowName = {'Wall'; 'Cliff'; 'Odometry'; 'Sonar'; 'LIDAR'; 'Camera'; 'RSDepth'};
            app.table_noise.ColumnEditable = [true true];
            app.table_noise.CellEditCallback = createCallbackFcn(app, @table_noise_CellEditCallback, true);
            app.table_noise.Tag = 'table_noise';
            app.text2 = uilabel(app.figure_config);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Noise Data';
            app.text3 = uilabel(app.figure_config);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Communication Delay';
            app.edit_comDelay = uieditfield(app.figure_config, 'text');
            app.edit_comDelay.ValueChangedFcn = createCallbackFcn(app, @edit_comDelay_Callback, true);
            app.edit_comDelay.Tag = 'edit_comDelay';
            app.edit_comDelay.Value = '0';
            app.push_load = uibutton(app.figure_config, 'push');
            app.push_load.ButtonPushedFcn = createCallbackFcn(app, @push_load_Callback, true);
            app.push_load.Tag = 'push_load';
            app.push_load.Text = 'Load';
            app.push_save = uibutton(app.figure_config, 'push');
            app.push_save.ButtonPushedFcn = createCallbackFcn(app, @push_save_Callback, true);
            app.push_save.Tag = 'push_save';
            app.push_save.Text = 'Save';
            % Show the figure after all components are created
            app.figure_config.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigMakerGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure_config)

                % Execute the startup function
                runStartupFcn(app, @(app)ConfigMakerGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure_config)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_config)
        end
    end
end