classdef MapMakerGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_mapmaker       matlab.ui.Figure
        push_load             matlab.ui.control.Button
        uipanel5              matlab.ui.container.ButtonGroup
        text18                matlab.ui.control.Label
        radio_vwall_high      matlab.ui.control.RadioButton
        radio_vwall_med       matlab.ui.control.RadioButton
        radio_vwall_low       matlab.ui.control.RadioButton
        text16                matlab.ui.control.Label
        push_vwall            matlab.ui.control.Button
        uipanel4              matlab.ui.container.ButtonGroup
        text17                matlab.ui.control.Label
        radio_beacon_yellow   matlab.ui.control.RadioButton
        radio_beacon_green    matlab.ui.control.RadioButton
        text11                matlab.ui.control.Label
        edit_beacon           matlab.ui.control.EditField
        radio_beacon_cyan     matlab.ui.control.RadioButton
        radio_beacon_magenta  matlab.ui.control.RadioButton
        radio_beacon_blue     matlab.ui.control.RadioButton
        radio_beacon_red      matlab.ui.control.RadioButton
        radio_beacon_white    matlab.ui.control.RadioButton
        radio_beacon_black    matlab.ui.control.RadioButton
        push_beacon           matlab.ui.control.Button
        uipanel3              matlab.ui.container.ButtonGroup
        SquareLineLabel       matlab.ui.control.Label
        ContLineLabel         matlab.ui.control.Label
        SingleLineLabel       matlab.ui.control.Label
        push_line_square      matlab.ui.control.Button
        push_line_cont        matlab.ui.control.Button
        push_line_line        matlab.ui.control.Button
        uipanel2              matlab.ui.container.ButtonGroup
        SquareWallLabel       matlab.ui.control.Label
        ContWallLabel         matlab.ui.control.Label
        SingleWallLabel       matlab.ui.control.Label
        push_wall_square      matlab.ui.control.Button
        push_wall_cont        matlab.ui.control.Button
        push_wall_line        matlab.ui.control.Button
        uipanel1              matlab.ui.container.ButtonGroup
        text4                 matlab.ui.control.Label
        text3                 matlab.ui.control.Label
        text6                 matlab.ui.control.Label
        pushbutton22          matlab.ui.control.Button
        pushbutton21          matlab.ui.control.Button
        pushbutton20          matlab.ui.control.Button
        push_save             matlab.ui.control.Button
        push_undo             matlab.ui.control.Button
        push_clear            matlab.ui.control.Button
        text1                 matlab.ui.control.Label
        axes_map              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mapMat % cell array containing the map data
    end
    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)  
            app.edit_beacon.UserData = 0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MapMakerGUI_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
                      
            % Initialize field to store map data with comments to improve readability
            app.mapMat = {'% File containing map information' ;
                sprintf('\n') ;
                '% Formatting:' ;
                '% wall x1 y1 x2 y2' ;
                '%   Order does not matter between the points';
                '% line x1 y1 x2 y2' ;
                '% beacon x y [r g b] ID_tag' ;
                '%   [r g b] is the red-green-blue color vector' ;
                '% virtwall x y theta' ;
                '%   Virtual walls emit from a location, not like real walls' ;
                '%   theta is the angle relative to the positive x-axis' ;
                sprintf('\n')};
        end

        % Value changed function: edit_beacon
        function edit_beacon_Callback(app, event)
            % update whether the user edited the beacon (stops the
            % autoincrement)
            app.edit_beacon.UserData = 1;
            
        end

        % Window key press function: figure_mapmaker
        function figure_mapmaker_WindowKeyPressFcn(app, event)
                  
            % Extract keypress information
            keydown= event.Key;
            mod= event.Modifier;
            % Enable Ctrl+z to undo
            if strcmp(keydown,'z') && length(mod) == 1 && strcmp(mod,'control')
                push_undo_Callback(app,event);
            end
        end

        % Button pushed function: push_beacon
        function push_beacon_Callback(app, event)
            [x, y]= ginput(1);
            
            % Check for valid input
            if ~isempty(x)
                xLimits= xlim(app.axes_map);
                yLimits= ylim(app.axes_map);
                if x >= xLimits(1) && x <= xLimits(2) && ...
                        y >= yLimits(1) && y <= yLimits(2)
                    % Figure out which radio button is selected for color
                    if app.radio_beacon_black.Value 
                        col= [0 0 0];
                    elseif app.radio_beacon_white.Value 
                        col= [1 1 1];
                    elseif app.radio_beacon_red.Value 
                        col= [1 0 0];
                    elseif app.radio_beacon_blue.Value 
                        col= [0 0 1];
                    elseif app.radio_beacon_magenta.Value 
                        col= [1 0 1];
                    elseif app.radio_beacon_cyan.Value 
                        col= [0 1 1];
                    elseif app.radio_beacon_green.Value 
                        col= [0 1 0];
                    else
                        col= [1 1 0];
                    end
            
                    % Update data and plot
                    id= app.edit_beacon.Value; 
                    %id(1)= [];  % Delete 'b'
                    app.mapMat= [app.mapMat ; ...
                        sprintf('beacon %.3f %.3f [%.1f %.1f %.1f] %s',...
                        x,y,col(1),col(2),col(3),id)];
                    axes(app.axes_map)
                    plot(app.axes_map,x,y,'Marker','o','Color',col)
                    text(app.axes_map,x,y,['  ' id])     % Add spaces to stop clutter
                    
            
                    % Increment beacon ID if unedited
                    if ~(app.edit_beacon.UserData)
                        idtext= app.edit_beacon.Value; 
                        idtext(1)= [];  % Delete 'b'
                        idnum= str2double(idtext);
                        app.edit_beacon.Value = ['b' num2str(idnum+1)]; 
                    end
                else
                    warning('MATLAB:ginput',...
                        'Point clicked is out of bounds.  Action has been cancelled.')
                end
            end
        end

        % Button pushed function: push_clear
        function push_clear_Callback(app, event)
                        
            % Check that map should be cleared
            button= questdlg('Are you sure you want to clear all map data?',...
                'Verify Clear','Yes','Cancel','Cancel');
            
            % If so then clear the plot and delete data in the matrix
            if strcmp(button,'Yes')
                children= get(gca,'Children');
                if ~isempty(children)
                    delete(children)             % Deletes from plot
                    app.mapMat= {'% File containing map information' ;
                        sprintf('\n') ;
                        '% Formatting:' ;
                        '% wall x1 y1 x2 y2' ;
                        '%   Order does not matter between the points';
                        '% line x1 y1 x2 y2' ;
                        '% beacon x y [r g b] ID_tag' ;
                        '%   [r g b] is the red-green-blue color vector' ;
                        '% virtwall x y theta' ;
                        '%   Virtual walls emit from a location, not like real walls' ;
                        '%   theta is the angle relative to the positive x-axis' ;
                        sprintf('\n')};
                end
            end
        end

        % Button pushed function: push_line_cont
        function push_line_cont_Callback(app, event)
                       
            % Plot continuous (non-breaking) lines
            axes(app.axes_map)
            
            [x1, y1, button]= ginput(1);          % Accept initial point
            
            % End point acceptance when keyboard button is pressed
            while ~isempty(button) && button >= 1 && button <= 3
                [x2, y2, button]= ginput(1);      % Accept second point in line

                if ~isempty(button) && button >= 1 && button <= 3
                    xLimits= xlim(app.axes_map);
                    yLimits= ylim(app.axes_map);
                    if all([x1 x2] >= xLimits(1)) && all([x1 x2] <= xLimits(2)) && ...
                            all([y1 y2] >= yLimits(1)) && all([y1 y2] <= yLimits(2))
                        app.mapMat= [app.mapMat ; ...
                            sprintf('line %.3f %.3f %.3f %.3f',x1,y1,x2,y2)];
                        plot(app.axes_map,[x1 x2],[y1 y2],'k--','LineWidth',1)
                        x1= x2;     % Save second point for next line starting point
                        y1= y2;
                    else
                        warning('MATLAB:ginput',...
                            'Point clicked is out of bounds.  Action has been cancelled.')
                    end
                end
            end
        end

        % Button pushed function: push_line_line
        function push_line_line_Callback(app, event)
       
            [x, y]= ginput(2);                   % Accept two mouse clicks
            if ~isempty(x) && length(x) == 2    % Check that two points were clicked
                xLimits= xlim(app.axes_map);
                yLimits= ylim(app.axes_map);
                if all(x >= xLimits(1)) && all(x <= xLimits(2)) && ...
                        all(y >= yLimits(1)) && all(y <= yLimits(2))
                    app.mapMat= [app.mapMat ; ...
                        sprintf('line %.3f %.3f %.3f %.3f',x(1),y(1),x(2),y(2))];
                    axes(app.axes_map)
                    plot(app.axes_map,x,y,'k--','LineWidth',1)
                else
                    warning('MATLAB:ginput',...
                        'Point clicked is out of bounds.  Action has been cancelled.')
                end
            end
        end

        % Button pushed function: push_line_square
        function push_line_square_Callback(app, event)
                        
            % Get corner data and add to map matrix
            [x, y]= ginput(2);   % Accept two mouse clicks
            if ~isempty(x) && length(x) == 2    % Check that two points were clicked
                xLimits= xlim(app.axes_map);
                yLimits= ylim(app.axes_map);
                if all(x >= xLimits(1)) && all(x <= xLimits(2)) && ...
                        all(y >= yLimits(1)) && all(y <= yLimits(2))
                    app.mapMat= [app.mapMat ; ...
                        sprintf('line %.3f %.3f %.3f %.3f',x(1),y(1),x(1),y(2)) ; ...
                        sprintf('line %.3f %.3f %.3f %.3f',x(1),y(2),x(2),y(2)) ; ...
                        sprintf('line %.3f %.3f %.3f %.3f',x(2),y(2),x(2),y(1)) ; ...
                        sprintf('line %.3f %.3f %.3f %.3f',x(2),y(1),x(1),y(1))];
                    axes(app.axes_map)
                    % Split up plot commands so that Undo will only take out one line
                    plot(app.axes_map,[x(1) x(1)],[y(1) y(2)],'k--','LineWidth',1)
                    plot(app.axes_map,[x(1) x(2)],[y(2) y(2)],'k--','LineWidth',1)
                    plot(app.axes_map,[x(2) x(2)],[y(2) y(1)],'k--','LineWidth',1)
                    plot(app.axes_map,[x(2) x(1)],[y(1) y(1)],'k--','LineWidth',1)
                else
                    warning('MATLAB:ginput',...
                        'Point clicked is out of bounds.  Action has been cancelled.')
                end
            end
        end

        % Button pushed function: push_load
        function push_load_Callback(app, event)
            
            % Import map file
            [filename, pathname, filter]= uigetfile('*.txt','Import Map File');
            if filename                         % Make sure cancel was not pressed
                fid= fopen([pathname filename]);% Get file handle for parsing
            
                % Clear old map
                axes(app.axes_map)
                children= get(gca,'Children');  % All ploted items on axes
                if ~isempty(children)
                    delete(children)	% Delete all but robot from axes
                end
                app.mapMat= {[]};
            
               % Parse the file and extract relevant information
                walls= [];
                lines= [];
                beacs= {};
                vwalls= [];
                while ~feof(fid)
                    line= fgetl(fid);
                    line= lower(line);  % Convert to lowercase if necessary
                    line= strtrim(line);    % Delete leading and trailing whitespace
                    app.mapMat= [app.mapMat ; line];% Save to map cell array
                    lineWords= {};          % To keep track of line entries
                    while ~isempty(line) && ~strcmp(line(1),'%')% End of line or comment
                        [word, line]= strtok(line);  % Get next entry
                        line= strtrim(line);        % To be able to detect comments
                        lineWords= [lineWords word];
                    end
            
                    %%% Regular expressions would probably be more efficient %%%
                    if length(lineWords) == 5 && strcmp(lineWords{1},'wall') && ...
                            ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            ~isnan(str2double(lineWords{4})) && ...
                            ~isnan(str2double(lineWords{5}))
                        walls= [walls ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) str2double(lineWords{4}) ...
                            str2double(lineWords{5})];
                    elseif length(lineWords) == 5 && strcmp(lineWords{1},'line') && ...
                            ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            ~isnan(str2double(lineWords{4})) && ...
                            ~isnan(str2double(lineWords{5}))
                        lines= [lines ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) str2double(lineWords{4}) ...
                            str2double(lineWords{5})];
                    elseif length(lineWords) == 7 && strcmp(lineWords{1},'beacon') ...
                            && ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            length(lineWords{4}) >= 2 && ...
                            strcmp(lineWords{4}(1),'[') && ...
                            ~isnan(str2double(lineWords{4}(2:end))) && ...
                            ~isnan(str2double(lineWords{5})) && ...
                            length(lineWords{6}) >= 2 && ...
                            strcmp(lineWords{6}(end),']') && ...
                            ~isnan(str2double(lineWords{6}(1:end-1)))
                        beacs= [beacs ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) ...
                            str2double(lineWords{4}(2:end)) ...
                            str2double(lineWords{5}) ...
                            str2double(lineWords{6}(1:end-1)) lineWords(7)];
                    elseif length(lineWords) == 5 && ...
                            strcmp(lineWords{1},'virtwall') && ...
                            ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            ~isnan(str2double(lineWords{4})) && ...
                            ~isnan(str2double(lineWords{5})) && ...
                            str2double(lineWords{5}) >= 1 && ...
                            str2double(lineWords{5}) <= 3
                        vwalls= [vwalls ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) str2double(lineWords{4}) ...
                            str2double(lineWords{5})];
                    elseif ~isempty(lineWords)
                        warning('MATLAB:invalidInput',...
                            'This line in map file %s is unrecognized:\n\t%s',...
                            filename,line)
                    end
                end
                fclose(fid);
            
            
                % Plot walls
                for i= 1:size(walls,1)
                    plot(app.axes_map,walls(i,[1 3]),walls(i,[2 4]),'k-','LineWidth',1)
                end
            
                % Plot lines
                for i= 1:size(lines,1)
                    plot(app.axes_map,lines(i,[1 3]),lines(i,[2 4]),'k--','LineWidth',1)
                end
            
                % Plot beacons
                for i= 1:size(beacs,1)
                    plot(app.axes_map,beacs{i,1},beacs{i,2},...
                        'Color',cell2mat(beacs(i,3:5)),'Marker','o')
                    text(app.axes_map,beacs{i,1},beacs{i,2},['  ' beacs{i,6}])
                end
            
                % Define virtual wall emitter constants
                halo_rad= 0.45;     % Radius of the halo around the emitter
                range_short= 2.13;  % Range of the wall on the 0-3' setting
                ang_short= 0.33;    % Angular range on the 0-3' setting
                range_med= 5.56;    % Range of the wall on the 4'-7' setting
                ang_med= 0.49;      % Angular range on the 4'-7' setting
                range_long= 8.08;   % Range of the wall on the 8'+ setting
                ang_long= 0.61;     % Angular range on the 8'+ setting
            
                % Get points to map virtual walls
                for i= 1:size(vwalls,1)
                    x_vw= vwalls(i,1);
                    y_vw= vwalls(i,2);
                    th_vw= vwalls(i,3);
                    if vwalls(i,4) == 1
                        range_vw= range_short;
                        ang_vw= ang_short;
                    elseif vwalls(i,4) == 2
                        range_vw= range_med;
                        ang_vw= ang_med;
                    else
                        range_vw= range_long;
                        ang_vw= ang_long;
                    end
                    x_1= x_vw+range_vw*cos(th_vw+ang_vw/2);
                    y_1= y_vw+range_vw*sin(th_vw+ang_vw/2);
                    x_2= x_vw+range_vw*cos(th_vw-ang_vw/2);
                    y_2= y_vw+range_vw*sin(th_vw-ang_vw/2);
            
                    % Plot halo around emitter and range triangle
                    circ_numPts= 21;    % Estimate circle as circ_numPts-1 lines
                    circ_ang=linspace(0,2*pi,circ_numPts);
                    circ_rad=ones(1,circ_numPts)*halo_rad;
                    [circ_x, circ_y]= pol2cart(circ_ang,circ_rad);
                    circ_x= circ_x+x_vw;
                    circ_y= circ_y+y_vw;
                    plot(app.axes_map,x_vw,y_vw,'g*')
                    plot(app.axes_map,circ_x,circ_y,'g:','LineWidth',1);
                    plot(app.axes_map,[x_vw x_1 x_2 x_vw],[y_vw y_1 y_2 y_vw],'g:','LineWidth',1)
                end
            end
        end

        % Button pushed function: push_save
        function push_save_Callback(app, event)
            
            % Pull up dialogue box to get filename
            fileName= inputdlg('Filename (no extension):','Save Map Data');
            if ~isempty(fileName)
                fileName= [fileName{1} '.txt']; % Convert cell array and add extension
            
                % Check if file is already in existence
                button= 'Yes';
                if exist(fileName,'file')
                    button= questdlg('A file by that name exists.  Overwrite?',...
                        'Verify Overwrite','Yes','Cancel','Cancel');
                end
            
                if strcmp(button,'Yes')
                    % Write map data to file
                    fid= fopen(fileName,'wt');
                    for i= 1:size(app.mapMat,1)
                        fprintf(fid,'%s\n',app.mapMat{i});
                    end
                    fclose(fid);
                end
            end
        end

        % Button pushed function: push_undo
        function push_undo_Callback(app, event)
            
            % Undo most recent plotting operation
            children= get(app.axes_map,'Children');
            if ~isempty(children)
                delete(children(1))             % Deletes from plot
            
                % Delete last line of map matrix
                last_line= app.mapMat{end};
                if strcmp(last_line(1:6),'beacon')
                    delete(children(2))         % Delete 2-part plotted items
                elseif strcmp(last_line(1:8),'virtwall')
                    delete(children(2:3))        % Delete 3-part plotted items
                end
                app.mapMat= app.mapMat(1:end-1);
            end
        end

        % Button pushed function: push_vwall
        function push_vwall_Callback(app, event)

            
            % Get virtual wall data and add to map matrix
            [x, y]= ginput(2);   % Accept two mouse clicks
            
            % Define virtual wall emitter constants
            halo_rad= 0.45;     % Radius of the halo around the emitter
            range_short= 2.13;  % Range of the wall on the 0-3' setting
            ang_short= 0.33;    % Angular range on the 0-3' setting
            range_med= 5.56;    % Range of the wall on the 4'-7' setting
            ang_med= 0.49;      % Angular range on the 4'-7' setting
            range_long= 8.08;   % Range of the wall on the 8'+ setting
            ang_long= 0.61;     % Angular range on the 8'+ setting
            
            % Get range data from radio buttons
            if app.radio_vwall_low.Value     % 0-3' range
                range= 1;
                range_dist= range_short;
                ang= ang_short;
            elseif app.radio_vwall_med.Value % 4'-7' range
                range= 2;
                range_dist= range_med;
                ang= ang_med;
            else                             % 8'+ range
                range= 3;
                range_dist= range_long;
                ang= ang_long;
            end
            
            if ~isempty(x) && length(x) == 2    % Check that two points were clicked
                xLimits= xlim(app.axes_map);
                yLimits= ylim(app.axes_map);
                if x(1) >= xLimits(1) && x(1) <= xLimits(2) && ...
                        y(1) >= yLimits(1) && y(1) <= yLimits(2)
                    % Calculate direction of virtual wall emission
                    th= atan2(y(2)-y(1),x(2)-x(1));
            
                    % Get endpoints of wall range triangle
                    x_1= x(1)+range_dist*cos(th+ang/2);
                    y_1= y(1)+range_dist*sin(th+ang/2);
                    x_2= x(1)+range_dist*cos(th-ang/2);
                    y_2= y(1)+range_dist*sin(th-ang/2);
            
                    % Plot halo around emitter and range triangle
                    circ_numPts= 21;    % Estimate circle as circ_numPts-1 lines
                    circ_ang=linspace(0,2*pi,circ_numPts);
                    circ_rad=ones(1,circ_numPts)*halo_rad;
                    [circ_x, circ_y]= pol2cart(circ_ang,circ_rad);
                    circ_x= circ_x+x(1);
                    circ_y= circ_y+y(1);
                    plot(app.axes_map,x(1),y(1),'g*')
                    plot(app.axes_map,circ_x,circ_y,'g:','LineWidth',1);
                    plot(app.axes_map,[x(1) x_1 x_2 x(1)],[y(1) y_1 y_2 y(1)],'g:','LineWidth',1)
            
                    % Save virtual wall data to map
                    app.mapMat= [app.mapMat ; ...
                        sprintf('virtwall %.3f %.3f %.3f %.0f',x(1),y(1),th,range)];
                else
                    warning('MATLAB:ginput',...
                        'Point clicked is out of bounds.  Action has been cancelled.')
                end
            end
        end

        % Button pushed function: push_wall_cont
        function push_wall_cont_Callback(app, event)
            % Plot continuous (non-breaking) walls
            axes(app.axes_map)
     
            [x1, y1, button]= ginput(1);          % Accept initial point
            
            
            % End point acceptance when keyboard button is pressed
            while ~isempty(button) && button >= 1 && button <= 3
                [x2, y2, button]= ginput(1);      % Accept second point in line
                if ~isempty(button) && button >= 1 && button <= 3
                    xLimits= xlim(app.axes_map);
                    yLimits= ylim(app.axes_map);
                    if all([x1 x2] >= xLimits(1)) && all([x1 x2] <= xLimits(2)) && ...
                            all([y1 y2] >= yLimits(1)) && all([y1 y2] <= yLimits(2))
                        app.mapMat= [app.mapMat ; ...
                            sprintf('wall %.3f %.3f %.3f %.3f',x1,y1,x2,y2)];
                        plot(app.axes_map,[x1 x2],[y1 y2],'k-','LineWidth',1)
                        x1= x2;     % Save second point for next line starting point
                        y1= y2;
                    else
                        warning('MATLAB:ginput',...
                            'Point clicked is out of bounds.  Action has been cancelled.')
                    end
                end
            end
        end

        % Button pushed function: push_wall_line
        function push_wall_line_Callback(app, event)
        
            [x, y]= ginput(2);                   % Accept two mouse clicks
            if ~isempty(x) && length(x) == 2    % Check that two points were clicked
                xLimits= xlim(app.axes_map);
                yLimits= ylim(app.axes_map);
                if all(x >= xLimits(1)) && all(x <= xLimits(2)) && ...
                        all(y >= yLimits(1)) && all(y <= yLimits(2))
                    app.mapMat= [app.mapMat ; ...
                        sprintf('wall %.3f %.3f %.3f %.3f',x(1),y(1),x(2),y(2))];
                    axes(app.axes_map)
                    plot(app.axes_map,x,y,'k-','LineWidth',1)
                else
                    warning('MATLAB:ginput',...
                        'Point clicked is out of bounds.  Action has been cancelled.')
                end
            end
        end

        % Button pushed function: push_wall_square
        function push_wall_square_Callback(app, event)
    
            % Get corner data and add to map matrix
            [x, y]= ginput(2);   % Accept two mouse clicks
            if ~isempty(x) && length(x) == 2    % Check that two points were clicked
                xLimits= xlim(app.axes_map);
                yLimits= ylim(app.axes_map);
                if all(x >= xLimits(1)) && all(x <= xLimits(2)) && ...
                        all(y >= yLimits(1)) && all(y <= yLimits(2))
                    app.mapMat= [app.mapMat ; ...
                        sprintf('wall %.3f %.3f %.3f %.3f',x(1),y(1),x(1),y(2)) ; ...
                        sprintf('wall %.3f %.3f %.3f %.3f',x(1),y(2),x(2),y(2)) ; ...
                        sprintf('wall %.3f %.3f %.3f %.3f',x(2),y(2),x(2),y(1)) ; ...
                        sprintf('wall %.3f %.3f %.3f %.3f',x(2),y(1),x(1),y(1))];
                    axes(app.axes_map)
                    % Split up plot commands so that Undo will only take out one line
                    plot(app.axes_map,[x(1) x(1)],[y(1) y(2)],'k-','LineWidth',1)
                    plot(app.axes_map,[x(1) x(2)],[y(2) y(2)],'k-','LineWidth',1)
                    plot(app.axes_map,[x(2) x(2)],[y(2) y(1)],'k-','LineWidth',1)
                    plot(app.axes_map,[x(2) x(1)],[y(1) y(1)],'k-','LineWidth',1)
                else
                    warning('MATLAB:ginput',...
                        'Point clicked is out of bounds.  Action has been cancelled.')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_mapmaker = uifigure('Visible', 'off');
            app.figure_mapmaker.Name = 'MapMakerGUI';
            app.figure_mapmaker.WindowKeyPressFcn = createCallbackFcn(app, @figure_mapmaker_WindowKeyPressFcn, true);
            app.figure_mapmaker.Tag = 'figure_mapmaker';
            app.axes_map = uiaxes(app.figure_mapmaker);
            app.axes_map.XLim = [-5 5];
            app.axes_map.YLim = [-5 5];
            app.axes_map.Tag = 'axes_map';
            app.text1 = uilabel(app.figure_mapmaker);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Map Maker';
            app.push_clear = uibutton(app.figure_mapmaker, 'push');
            app.push_clear.ButtonPushedFcn = createCallbackFcn(app, @push_clear_Callback, true);
            app.push_clear.Tag = 'push_clear';
            app.push_clear.Text = 'Clear';
            app.push_undo = uibutton(app.figure_mapmaker, 'push');
            app.push_undo.ButtonPushedFcn = createCallbackFcn(app, @push_undo_Callback, true);
            app.push_undo.Tag = 'push_undo';
            app.push_undo.Text = 'Undo';
            app.push_save = uibutton(app.figure_mapmaker, 'push');
            app.push_save.ButtonPushedFcn = createCallbackFcn(app, @push_save_Callback, true);
            app.push_save.Tag = 'push_save';
            app.push_save.Text = 'Save';
            app.uipanel1 = uibuttongroup(app.figure_mapmaker);
            app.uipanel1.Title = 'Shapes';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton20 = uibutton(app.uipanel1, 'push');
            app.pushbutton20.Tag = 'pushbutton20';
            app.pushbutton20.Enable = 'off';
            app.pushbutton20.Text = '';
            app.pushbutton21 = uibutton(app.uipanel1, 'push');
            app.pushbutton21.Tag = 'pushbutton21';
            app.pushbutton21.Enable = 'off';
            app.pushbutton21.Text = '';
            app.pushbutton22 = uibutton(app.uipanel1, 'push');
            app.pushbutton22.Tag = 'pushbutton22';
            app.pushbutton22.Enable = 'off';
            app.pushbutton22.Text = '';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Continuous Line - press any key to stop';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Single Line - click endpoints';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Square - click diagonal corners';
            app.uipanel2 = uibuttongroup(app.figure_mapmaker);
            app.uipanel2.Title = 'Wall';
            app.uipanel2.Tag = 'uipanel2';
            app.push_wall_line = uibutton(app.uipanel2, 'push');
            app.push_wall_line.ButtonPushedFcn = createCallbackFcn(app, @push_wall_line_Callback, true);
            app.push_wall_line.Tag = 'push_wall_line';
            app.push_wall_line.Text = {''; ''};
            app.push_wall_cont = uibutton(app.uipanel2, 'push');
            app.push_wall_cont.ButtonPushedFcn = createCallbackFcn(app, @push_wall_cont_Callback, true);
            app.push_wall_cont.Tag = 'push_wall_cont';
            app.push_wall_cont.Text = '';
            app.push_wall_square = uibutton(app.uipanel2, 'push');
            app.push_wall_square.ButtonPushedFcn = createCallbackFcn(app, @push_wall_square_Callback, true);
            app.push_wall_square.Tag = 'push_wall_square';
            app.push_wall_square.Text = '';
            app.SingleWallLabel = uilabel(app.uipanel2);
            app.SingleWallLabel.Text = 'Single Wall';
            app.ContWallLabel = uilabel(app.uipanel2);
            app.ContWallLabel.Text = 'Cont. Wall';
            app.SquareWallLabel = uilabel(app.uipanel2);
            app.SquareWallLabel.Text = 'Square Wall';
            app.uipanel3 = uibuttongroup(app.figure_mapmaker);
            app.uipanel3.Title = 'Line';
            app.uipanel3.Tag = 'uipanel3';
            app.push_line_line = uibutton(app.uipanel3, 'push');
            app.push_line_line.ButtonPushedFcn = createCallbackFcn(app, @push_line_line_Callback, true);
            app.push_line_line.Tag = 'push_line_line';
            app.push_line_line.Text = '';
            app.push_line_cont = uibutton(app.uipanel3, 'push');
            app.push_line_cont.ButtonPushedFcn = createCallbackFcn(app, @push_line_cont_Callback, true);
            app.push_line_cont.Tag = 'push_line_cont';
            app.push_line_cont.Text = '';
            app.push_line_square = uibutton(app.uipanel3, 'push');
            app.push_line_square.ButtonPushedFcn = createCallbackFcn(app, @push_line_square_Callback, true);
            app.push_line_square.Tag = 'push_line_square';
            app.push_line_square.Text = '';
            app.SingleLineLabel = uilabel(app.uipanel3);
            app.SingleLineLabel.Text = 'Single Line';
            app.ContLineLabel = uilabel(app.uipanel3);
            app.ContLineLabel.Text = 'Cont. Line';
            app.SquareLineLabel = uilabel(app.uipanel3);
            app.SquareLineLabel.Text = 'Square Line';
            app.uipanel4 = uibuttongroup(app.figure_mapmaker);
            app.uipanel4.Title = 'Beacon';
            app.uipanel4.Tag = 'uipanel4';
            app.push_beacon = uibutton(app.uipanel4, 'push');
            app.push_beacon.ButtonPushedFcn = createCallbackFcn(app, @push_beacon_Callback, true);
            app.push_beacon.Tag = 'push_beacon';
            app.push_beacon.Text = 'o';
            app.radio_beacon_black = uiradiobutton(app.uipanel4);
            app.radio_beacon_black.Tag = 'radio_beacon_black';
            app.radio_beacon_black.Text = 'Black';
            app.radio_beacon_black.Value = true;
            app.radio_beacon_white = uiradiobutton(app.uipanel4);
            app.radio_beacon_white.Tag = 'radio_beacon_white';
            app.radio_beacon_white.Text = 'White';
            app.radio_beacon_red = uiradiobutton(app.uipanel4);
            app.radio_beacon_red.Tag = 'radio_beacon_red';
            app.radio_beacon_red.Text = 'Red';
            app.radio_beacon_blue = uiradiobutton(app.uipanel4);
            app.radio_beacon_blue.Tag = 'radio_beacon_blue';
            app.radio_beacon_blue.Text = 'Blue';
            app.radio_beacon_magenta = uiradiobutton(app.uipanel4);
            app.radio_beacon_magenta.Tag = 'radio_beacon_magenta';
            app.radio_beacon_magenta.Text = 'Magenta';
            app.radio_beacon_cyan = uiradiobutton(app.uipanel4);
            app.radio_beacon_cyan.Tag = 'radio_beacon_cyan';
            app.radio_beacon_cyan.Text = 'Cyan';
            app.edit_beacon = uieditfield(app.uipanel4, 'text');
            app.edit_beacon.ValueChangedFcn = createCallbackFcn(app, @edit_beacon_Callback, true);
            app.edit_beacon.Tag = 'edit_beacon';
            app.edit_beacon.Value = 'b1';
            app.text11 = uilabel(app.uipanel4);
            app.text11.Tag = 'text11';
            app.text11.Text = 'ID Tag';
            app.radio_beacon_green = uiradiobutton(app.uipanel4);
            app.radio_beacon_green.Tag = 'radio_beacon_green';
            app.radio_beacon_green.Text = 'Green';
            app.radio_beacon_yellow = uiradiobutton(app.uipanel4);
            app.radio_beacon_yellow.Tag = 'radio_beacon_yellow';
            app.radio_beacon_yellow.Text = 'Yellow';
            app.text17 = uilabel(app.uipanel4);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Set Color and ID, then click button';
            app.uipanel5 = uibuttongroup(app.figure_mapmaker);
            app.uipanel5.Title = 'Virtual Wall';
            app.uipanel5.Tag = 'uipanel5';
            app.push_vwall = uibutton(app.uipanel5, 'push');
            app.push_vwall.ButtonPushedFcn = createCallbackFcn(app, @push_vwall_Callback, true);
            app.push_vwall.Tag = 'push_vwall';
            app.push_vwall.Text = '';
            app.text16 = uilabel(app.uipanel5);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Click emitter point then direction';
            app.radio_vwall_low = uiradiobutton(app.uipanel5);
            app.radio_vwall_low.Tag = 'radio_vwall_low';
            app.radio_vwall_low.Text = '0-3''';
            app.radio_vwall_low.Value = true;
            app.radio_vwall_med = uiradiobutton(app.uipanel5);
            app.radio_vwall_med.Tag = 'radio_vwall_med';
            app.radio_vwall_med.Text = '4''-7''';
            app.radio_vwall_high = uiradiobutton(app.uipanel5);
            app.radio_vwall_high.Tag = 'radio_vwall_high';
            app.radio_vwall_high.Text = '8''+';
            app.text18 = uilabel(app.uipanel5);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Range:';
            app.push_load = uibutton(app.figure_mapmaker, 'push');
            app.push_load.ButtonPushedFcn = createCallbackFcn(app, @push_load_Callback, true);
            app.push_load.Tag = 'push_load';
            app.push_load.Text = 'Load';
            % Show the figure after all components are created
            app.figure_mapmaker.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapMakerGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure_mapmaker)

                % Execute the startup function
                runStartupFcn(app, @(app)MapMakerGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure_mapmaker)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_mapmaker)
        end
    end
end