classdef ReplayGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_replay   matlab.ui.Figure
        text_title      matlab.ui.control.Label
        push_map        matlab.ui.control.Button
        uipanel3        matlab.ui.container.ButtonGroup
        radio_global    matlab.ui.control.RadioButton
        radio_centric   matlab.ui.control.RadioButton
        push_play       matlab.ui.control.Button
        push_pause      matlab.ui.control.Button
        push_data       matlab.ui.control.Button
        push_stop       matlab.ui.control.Button
        push_slow_fwd   matlab.ui.control.Button
        push_slow_back  matlab.ui.control.Button
        edit_display    matlab.ui.control.TextArea
        text_IOData     matlab.ui.control.Label
        text9           matlab.ui.control.Label
        axes_map        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function clearAllFlags(app, handles)
            % Set all playback mode flags to off
            
            set(handles.push_slow_back,'UserData',0)
            set(handles.push_slow_fwd,'UserData',0)
            set(handles.push_play,'UserData',0)
        end
        
        function stepPlayback(app, handles, stepSize)
            % Display data and move the robot corresponding to the current data index
            % and the step size to increase or decrease that index
            
            % Input:
            % stepSize - Double, number of data points to move,
            %   positive for forward movement, negative for backward
            
            % Get important data and update index
            datahistory= get(handles.edit_display,'UserData');
            idx= get(handles.text_IOData,'UserData')+stepSize;
            set(handles.text_IOData,'UserData',idx)
            handles_robot= get(handles.figure_replay,'UserData');
            str_disp= get(handles.edit_display,'String');
            
            % Get the new robot position
            t= datahistory{idx,1};
            x= datahistory{idx,2};
            y= datahistory{idx,3};
            th= datahistory{idx,4};
            
            % Line approximation of the robot
            circ_numPts= 21;    % Estimate circle as circ_numPts-1 lines
            circ_ang=linspace(0,2*pi,circ_numPts);
            circ_rad=ones(1,circ_numPts)*0.2;
            [circ_x circ_y]= pol2cart(circ_ang,circ_rad);
            circ_x=circ_x+x;
            circ_y=circ_y+y;
            
            % Update robot circle and direction line
            set(handles_robot(1),'XData',circ_x)
            set(handles_robot(1),'YData',circ_y)
            set(handles_robot(2),'XData',[x x+1.5*0.2*cos(th)])
            set(handles_robot(2),'YData',[y y+1.5*0.2*sin(th)])
            
            % If in robot-centric view mode move plot focal point
            if get(handles.radio_centric,'Value')
                curr_xlimit= get(handles.axes_map,'XLim');
                curr_ylimit= get(handles.axes_map,'YLim');
                half_xdist= (curr_xlimit(2)-curr_xlimit(1))/2;
                half_ydist= (curr_ylimit(2)-curr_ylimit(1))/2;
                set(handles.axes_map,'XLim',[x-half_xdist x+half_xdist])
                set(handles.axes_map,'YLim',[y-half_ydist y+half_ydist])
            end
            
            % Define function display string
            fcn_str= '';
            fcn_data= datahistory{idx,5};
            if ischar(fcn_data)             % Single function call this time step
                fcn_str= fcn_data;
            elseif iscell(fcn_data)         % Multiple function calls this time step
                fcn_str= fcn_data{1};       % Avoid extra line breaks
                for i= 2:length(fcn_data)
                    fcn_str= sprintf('%s  %s',fcn_str,fcn_data{i});
                end
            end
            
            % Update the data display in accordance with the new index
            if stepSize < 0     % Move backwards
                str_disp= str_disp(1:end+stepSize); % Delete lines from end
            elseif stepSize > 0 % Move forwards
                str_disp= [str_disp ; ...
                    {sprintf('%.2f %.3f %.3f %.3f %s',t,x,y,th,fcn_str)}];
            end
            set(handles.edit_display,'String',str_disp)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ReplayGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            
            % UIWAIT makes ReplayGUI wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            % Choose default command line output for ReplayGUI
            % Output all handles for use with updating
            handles.output = handles;
            
            % Plot robot in default position and store plot handles for updating
            axes(handles.axes_map)
            circ_numPts= 21;    % Estimate circle as circ_numPts-1 lines
            circ_ang=linspace(0,2*pi,circ_numPts);
            circ_rad=ones(1,circ_numPts)*0.2;
            [circ_x circ_y]= pol2cart(circ_ang,circ_rad);
            handle_circ= plot(circ_x,circ_y,'b-','LineWidth',1.5);
            set(handle_circ,'Visible','off')
            handle_line= plot([0 1.5*0.2],[0 0],'b-','LineWidth',1.5);
            set(handle_line,'Visible','off')
            set(handles.figure_replay,'UserData',[handle_circ ; handle_line])
            
            % Set the current index of the data file to UserData in the text
            set(handles.text_IOData,'UserData',0)
            
            % Add in axes toolbar
            %set(hObject,'toolbar','figure');
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Close request function: figure_replay
        function figure_replay_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure_replay (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hint: delete(hObject) closes the figure
            
            % Clear playback flags to avoid errors
            % Don't try to clear them if opened as just a figure, not with program
            if ~isempty(handles)
                clearAllFlags(app, handles)
            end
            
            % Close the figure
            delete(hObject);
        end

        % Window key press function: figure_replay
        function figure_replay_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure_replay (see GCBO)
            % eventdata  structure with the following fields (see FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            
            % Check which key is pressed and move time step accordingly
            %   Left and up arrows step backwards
            %   Right and down arrows step forwards
            %   Space, enter, and esc pause
            keydown= eventdata.Key;  % Assume only one key pressed at a time
            if any(strcmp(keydown,{'leftarrow' 'uparrow'}))
                % Pause playback
                clearAllFlags(app, handles)
            
                % Get data for checking out of bounds
                idx= get(handles.text_IOData,'UserData');
            
                % Rewind one frame
                if idx > 1
                    stepPlayback(app, handles,-1)
                end
            elseif any(strcmp(keydown,{'rightarrow' 'downarrow'}))
                % Pause playback
                clearAllFlags(app, handles)
            
                % Get data for checking out of bounds
                datahistory= get(handles.edit_display,'UserData');
                idx= get(handles.text_IOData,'UserData');
            
                % Foward one frame
                if idx < size(datahistory,1)
                    stepPlayback(app, handles,1)
                end
            elseif any(strcmp(keydown,{'space' 'return' 'escape'})) && ...
                    strcmp(get(handles.push_stop,'Enable'),'on')
                % Pause playback
                clearAllFlags(app, handles)
            end
        end

        % Button pushed function: push_data
        function push_data_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_data (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Import the data from the selected file into user data in the edit box
            [filename pathname filter]= uigetfile('*.mat','Import Data File');
            if filename                         % Make sure cancel was not pressed
                datastruct= load([pathname filename]);
                if isfield(datastruct,'datahistory')
                    datahistory= datastruct.datahistory;
                    if ~isempty(datahistory) && iscell(datahistory) && ...
                            size(datahistory,2) == 5
                        % Store the data
                        set(handles.edit_display,'UserData',datahistory)
            
                        % Set the robot to the first data point
                        idx= get(handles.text_IOData,'UserData');
                        stepPlayback(app, handles,-(idx-1))
                        handles_robot= get(handles.figure_replay,'UserData');
                        set(handles_robot,'Visible','on')
                    else
                        warning('MATLAB:invalidInput','Input data file not recognized')
                    end
                else
                    warning('MATLAB:invalidInput','Input data file not recognized')
                end
            end
        end

        % Button pushed function: push_map
        function push_map_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_map (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Import map file
            [filename pathname filter]= uigetfile('*.txt','Import Map File');
            if filename                         % Make sure cancel was not pressed
                fid= fopen([pathname filename]);% Get file handle for parsing
            
                % Parse the file and extract relevant information
                walls= [];
                lines= [];
                beacs= {};
                vwalls= [];
                while ~feof(fid)
                    fline= fgetl(fid);
                    line= lower(fline);     % Convert to lowercase if necessary
                    line= strtrim(line);    % Delete leading and trailing whitespace
                    lineWords= {};          % To keep track of line entries
                    while ~isempty(line) && ~strcmp(line(1),'%')% End of line or comment
                        [word line]= strtok(line);  % Get next entry
                        line= strtrim(line);        % To be able to detect comments
                        lineWords= [lineWords word];
                    end
            
                    %%% Regular expressions would probably be more efficient %%%
                    if length(lineWords) == 5 && strcmp(lineWords{1},'wall') && ...
                            ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            ~isnan(str2double(lineWords{4})) && ...
                            ~isnan(str2double(lineWords{5}))
                        walls= [walls ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) str2double(lineWords{4}) ...
                            str2double(lineWords{5})];
                    elseif length(lineWords) == 5 && strcmp(lineWords{1},'line') && ...
                            ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            ~isnan(str2double(lineWords{4})) && ...
                            ~isnan(str2double(lineWords{5}))
                        lines= [lines ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) str2double(lineWords{4}) ...
                            str2double(lineWords{5})];
                    elseif length(lineWords) == 7 && strcmp(lineWords{1},'beacon') ...
                            && ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            length(lineWords{4}) >= 2 && ...
                            strcmp(lineWords{4}(1),'[') && ...
                            ~isnan(str2double(lineWords{4}(2:end))) && ...
                            ~isnan(str2double(lineWords{5})) && ...
                            length(lineWords{6}) >= 2 && ...
                            strcmp(lineWords{6}(end),']') && ...
                            ~isnan(str2double(lineWords{6}(1:end-1)))
                        beacs= [beacs ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) ...
                            str2double(lineWords{4}(2:end)) ...
                            str2double(lineWords{5}) ...
                            str2double(lineWords{6}(1:end-1)) lineWords(7)];
                    elseif length(lineWords) == 5 && ...
                            strcmp(lineWords{1},'virtwall') && ...
                            ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            ~isnan(str2double(lineWords{4})) && ...
                            ~isnan(str2double(lineWords{5})) && ...
                            str2double(lineWords{5}) >= 1 && ...
                            str2double(lineWords{5}) <= 3
                        vwalls= [vwalls ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) str2double(lineWords{4}) ...
                            str2double(lineWords{5})];
                    elseif ~isempty(lineWords)
                        warning('MATLAB:invalidInput',...
                            'This line in map file %s is unrecognized:\n\t%s',...
                            filename,fline)
                    end
                end
                fclose(fid);
            
                % Clear old map
                axes(handles.axes_map)
                children= get(gca,'Children');  % All ploted items on axes
                handles_robot= get(handles.figure_replay,'UserData');
                for i= 1:length(handles_robot)  % Keep sensors visualization
                    children(find(handles_robot(i) == children))= [];
                end
                if ~isempty(children)
                    delete(children)            % Delete all but robot from axes
                end
            
                % Plot walls
                for i= 1:size(walls,1)
                    plot(walls(i,[1 3]),walls(i,[2 4]),'k-','LineWidth',1)
                end
            
                % Plot lines
                for i= 1:size(lines,1)
                    plot(lines(i,[1 3]),lines(i,[2 4]),'k--','LineWidth',1)
                end
            
                % Plot beacons
                for i= 1:size(beacs,1)
                    plot(beacs{i,1},beacs{i,2},...
                        'Color',cell2mat(beacs(i,3:5)),'Marker','o')
                    text(beacs{i,1},beacs{i,2},['  ' beacs{i,6}])
                end
            
                % Plot virtual walls
                % Define virtual wall emitter constants
                halo_rad= 0.45;     % Radius of the halo around the emitter
                range_short= 2.13;  % Range of the wall on the 0-3' setting
                ang_short= 0.33;    % Angular range on the 0-3' setting
                range_med= 5.56;    % Range of the wall on the 4'-7' setting
                ang_med= 0.49;      % Angular range on the 4'-7' setting
                range_long= 8.08;   % Range of the wall on the 8'+ setting
                ang_long= 0.61;     % Angular range on the 8'+ setting
            
                % Get points to map virtual walls
                for i= 1:size(vwalls,1)
                    x_vw= vwalls(i,1);
                    y_vw= vwalls(i,2);
                    th_vw= vwalls(i,3);
                    if vwalls(i,4) == 1
                        range_vw= range_short;
                        ang_vw= ang_short;
                    elseif vwalls(i,4) == 2
                        range_vw= range_med;
                        ang_vw= ang_med;
                    else
                        range_vw= range_long;
                        ang_vw= ang_long;
                    end
                    x_1= x_vw+range_vw*cos(th_vw+ang_vw/2);
                    y_1= y_vw+range_vw*sin(th_vw+ang_vw/2);
                    x_2= x_vw+range_vw*cos(th_vw-ang_vw/2);
                    y_2= y_vw+range_vw*sin(th_vw-ang_vw/2);
            
                    % Plot halo around emitter and range triangle
                    circ_numPts= 21;    % Estimate circle as circ_numPts-1 lines
                    circ_ang=linspace(0,2*pi,circ_numPts);
                    circ_rad=ones(1,circ_numPts)*halo_rad;
                    [circ_x circ_y]= pol2cart(circ_ang,circ_rad);
                    circ_x= circ_x+x_vw;
                    circ_y= circ_y+y_vw;
                    plot(x_vw,y_vw,'g*')
                    plot(circ_x,circ_y,'g:','LineWidth',1);
                    plot([x_vw x_1 x_2 x_vw],[y_vw y_1 y_2 y_vw],'g:','LineWidth',1)
                end
            end
        end

        % Button pushed function: push_pause
        function push_pause_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_pause (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Clear all playback flags to pause playback
            clearAllFlags(app, handles)
        end

        % Button pushed function: push_play
        function push_play_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_play (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set the Play flag, clear other flags
            clearAllFlags(app, handles)
            set(handles.push_play,'UserData',1)
            
            % Get data for timing
            datahistory= get(handles.edit_display,'UserData');
            idx= get(handles.text_IOData,'UserData');
            
            % Loop while play flag is still set
            while exist('hObject','var') && get(handles.push_play,'UserData') ...
                    && idx < size(datahistory,1)
                % Display data and move robot for the current time step
                stepPlayback(app, handles,1)
                idx= get(handles.text_IOData,'UserData');
                pause(datahistory{idx,1}-datahistory{idx-1,1})
            end
        end

        % Button pushed function: push_slow_back
        function push_slow_back_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_slow_back (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set the Slow Rewind flag, clear other flags
            clearAllFlags(app, handles)
            set(handles.push_slow_back,'UserData',1)
            
            % Get data for timing
            datahistory= get(handles.edit_display,'UserData');
            idx= get(handles.text_IOData,'UserData');
            
            % Loop while Slow Rewind flag is still set
            while exist('hObject','var') && get(handles.push_slow_back,'UserData') ...
                    && idx > 1
                % Display data and move robot for the current time step
                slow_factor= 4;
                pause(slow_factor*(datahistory{idx,1}-datahistory{idx-1,1}))
                stepPlayback(app, handles,-1)
                idx= get(handles.text_IOData,'UserData');
            end
        end

        % Button pushed function: push_slow_fwd
        function push_slow_fwd_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_slow_fwd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set the Play flag, clear other flags
            clearAllFlags(app, handles)
            set(handles.push_slow_fwd,'UserData',1)
            
            % Get data for timing
            datahistory= get(handles.edit_display,'UserData');
            idx= get(handles.text_IOData,'UserData');
            
            % Loop while play flag is still set
            while exist('hObject','var') && get(handles.push_slow_fwd,'UserData') ...
                    && idx < size(datahistory,1)
                % Display data and move robot for the current time step
                stepPlayback(app, handles,1)
                idx= get(handles.text_IOData,'UserData');
                slow_factor= 4;
                pause(slow_factor*(datahistory{idx,1}-datahistory{idx-1,1}))
            end
        end

        % Button pushed function: push_stop
        function push_stop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_stop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Clear all playback flags to pause playback
            clearAllFlags(app, handles)
            
            % Restart the playback from the beginning
            idx= get(handles.text_IOData,'UserData');
            stepPlayback(app, handles,-(idx-1))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_replay = uifigure('Visible', 'off');
            app.figure_replay.Name = 'ReplayGUI';
            app.figure_replay.CloseRequestFcn = createCallbackFcn(app, @figure_replay_CloseRequestFcn, true);
            app.figure_replay.WindowKeyPressFcn = createCallbackFcn(app, @figure_replay_WindowKeyPressFcn, true);
            app.figure_replay.Tag = 'figure_replay';
            app.text_title = uilabel(app.figure_replay);
            app.text_title.Tag = 'text_title';
            app.text_title.Text = 'Autonomous Code Playback';
            app.push_map = uibutton(app.figure_replay, 'push');
            app.push_map.ButtonPushedFcn = createCallbackFcn(app, @push_map_Callback, true);
            app.push_map.Tag = 'push_map';
            app.push_map.Text = 'Load Map';
            app.uipanel3 = uibuttongroup(app.figure_replay);
            app.uipanel3.Title = 'Camera Position';
            app.uipanel3.Tag = 'uipanel3';
            app.radio_global = uiradiobutton(app.uipanel3);
            app.radio_global.Tag = 'radio_global';
            app.radio_global.Text = 'Global';
            app.radio_global.Value = true;
            app.radio_centric = uiradiobutton(app.uipanel3);
            app.radio_centric.Tag = 'radio_centric';
            app.radio_centric.Text = 'Robot Centric';
            app.push_play = uibutton(app.figure_replay, 'push');
            app.push_play.ButtonPushedFcn = createCallbackFcn(app, @push_play_Callback, true);
            app.push_play.Tag = 'push_play';
            app.push_play.Tooltip = 'Play';
            app.push_play.Text = '';
            app.push_pause = uibutton(app.figure_replay, 'push');
            app.push_pause.ButtonPushedFcn = createCallbackFcn(app, @push_pause_Callback, true);
            app.push_pause.Tag = 'push_pause';
            app.push_pause.Tooltip = 'Pause';
            app.push_pause.Text = '';
            app.push_data = uibutton(app.figure_replay, 'push');
            app.push_data.ButtonPushedFcn = createCallbackFcn(app, @push_data_Callback, true);
            app.push_data.Tag = 'push_data';
            app.push_data.Text = 'Load Data';
            app.push_stop = uibutton(app.figure_replay, 'push');
            app.push_stop.ButtonPushedFcn = createCallbackFcn(app, @push_stop_Callback, true);
            app.push_stop.Tag = 'push_stop';
            app.push_stop.Tooltip = 'Stop';
            app.push_stop.Text = '';
            app.push_slow_fwd = uibutton(app.figure_replay, 'push');
            app.push_slow_fwd.ButtonPushedFcn = createCallbackFcn(app, @push_slow_fwd_Callback, true);
            app.push_slow_fwd.Tag = 'push_slow_fwd';
            app.push_slow_fwd.Tooltip = 'Slow Forward';
            app.push_slow_fwd.Text = '>';
            app.push_slow_back = uibutton(app.figure_replay, 'push');
            app.push_slow_back.ButtonPushedFcn = createCallbackFcn(app, @push_slow_back_Callback, true);
            app.push_slow_back.Tag = 'push_slow_back';
            app.push_slow_back.Tooltip = 'Slow Rewind';
            app.push_slow_back.Text = '<';
            app.edit_display = uitextarea(app.figure_replay);
            app.edit_display.Tag = 'edit_display';
            app.edit_display.Enable = 'off';
            app.text_IOData = uilabel(app.figure_replay);
            app.text_IOData.Tag = 'text_IOData';
            app.text_IOData.Text = 'I/O Data';
            app.text9 = uilabel(app.figure_replay);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Time    X       Y   Theta  Function Call';
            app.axes_map = uiaxes(app.figure_replay);
            app.axes_map.XLim = [-5 5];
            app.axes_map.YLim = [-5 5];
            app.axes_map.Tag = 'axes_map';
            % Show the figure after all components are created
            app.figure_replay.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReplayGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure_replay)

                % Execute the startup function
                runStartupFcn(app, @(app)ReplayGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure_replay)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_replay)
        end
    end
end