classdef SimulatorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_simulator  matlab.ui.Figure
        uipanel6          matlab.ui.container.ButtonGroup
        radio_speed2      matlab.ui.control.RadioButton
        radio_speed3      matlab.ui.control.RadioButton
        radio_speed1      matlab.ui.control.RadioButton
        uipanel5          matlab.ui.container.Panel
        text_adv          matlab.ui.control.Label
        text_play         matlab.ui.control.Label
        text_power        matlab.ui.control.Label
        toggle_adv        matlab.ui.control.StateButton
        toggle_play       matlab.ui.control.StateButton
        uipanel_auto      matlab.ui.container.ButtonGroup
        chkbx_auto_save   matlab.ui.control.CheckBox
        push_auto_stop    matlab.ui.control.Button
        push_auto_start   matlab.ui.control.Button
        uipanel3          matlab.ui.container.ButtonGroup
        radio_centric     matlab.ui.control.RadioButton
        radio_global      matlab.ui.control.RadioButton
        text7             matlab.ui.control.Label
        push_origin       matlab.ui.control.Button
        push_config       matlab.ui.control.Button
        push_map          matlab.ui.control.Button
        uipanel2          matlab.ui.container.ButtonGroup
        push_fwd          matlab.ui.control.Button
        push_stop         matlab.ui.control.Button
        push_left         matlab.ui.control.Button
        push_back         matlab.ui.control.Button
        push_right        matlab.ui.control.Button
        uipanel1          matlab.ui.container.ButtonGroup
        chkbx_rsdepth     matlab.ui.control.CheckBox
        chkbx_lidar       matlab.ui.control.CheckBox
        chkbx_cliff       matlab.ui.control.CheckBox
        chkbx_bump        matlab.ui.control.CheckBox
        chkbx_sonar       matlab.ui.control.CheckBox
        chkbx_wall        matlab.ui.control.CheckBox
        push_sensors      matlab.ui.control.Button
        text_title        matlab.ui.control.Label
        axes_map          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        robotObj % create robot obj
        handles_sensors 
        handle_line
        handle_circ
        timerSim
        %toolbar
    end
    
    methods (Access = public)
        
        %function results = func(app)
            
        function updateSimInternal(app, timerSim, event)
            %@updateSim,app.robotObj,app
            % updateSim(timerSim,event,obj,handlesGUI)
            % Update the simulation of the robot.  Use current position and movement
            % information to plot the next step and update the robot information
            %
            % Input:
            % timerSim - Timer object (required argument for timers)
            % event - Structure, contains information about timer call
            %   (required argument for timers)
            % obj - CreateRobot object, contains information about robot
            % handlesGUI - Structure, handles to all GUI objects for updating plot
            robotRad = app.robotObj.radius; %.16;                 % Should be equal to obj.radius,
                                    % which cannot be accessed from here.

            % Get time since last update
            tStep= timerSim.InstantPeriod;
            if isnan(tStep)                 % First function call
                tStep= timerSim.Period;     % Assume period is correct
            end
            
            % Use a multiplier on the time step based on simulation speed
            if get(app.radio_speed2,'Value')
                tStep= tStep*2;
            elseif get(app.radio_speed3,'Value')
                tStep= tStep*3;
            end
            
            % Extract values
            state= getState(app.robotObj);
            x= state(1);
            y= state(2);
            v= state(4:5);
            
            % Check for collisions with walls
            collPts= findCollisions(app.robotObj);
            
            % Depending on if walls are hit, update position differently
            if isempty(collPts)         % No walls
                driveNormal(app.robotObj,tStep)
            elseif size(collPts,1) == 1 % One wall
                if ~collPts(4)          % No corner
                    drive1Wall(app.robotObj,tStep,collPts)
                else                    % One corner
                    driveCorner(app.robotObj,tStep,collPts)
                end
            else                        % Two walls
                if ~any(collPts(:,4))   % No corners
                    drive2Wall(app.robotObj,tStep,collPts)
                elseif xor(collPts(1,4),collPts(2,4))   % One corner
                    collPts= collPts(find(~collPts(:,4)),:);
                    drive1Wall(app.robotObj,tStep,collPts)       % Only look at wall
                else                    % Two corners
                    % Only look at corner that is closest to the trajectory
                    vec1= [collPts(1,1)-x collPts(1,2)-y]/...
                        sqrt((collPts(1,1)-x)^2+(collPts(1,2)-y)^2);
                    vec2= [collPts(2,1)-x collPts(2,2)-y]/...
                        sqrt((collPts(2,1)-x)^2+(collPts(2,2)-y)^2);
                    [closest,closeIdx]= max([dot(v,vec1) dot(v,vec2)]);
                    collPts= collPts(closeIdx,:);
                    driveCorner(app.robotObj,tStep,collPts)
                end
            end
            
            % Update the output data
            updateOutput(app.robotObj)
            
            % Extract updated state values
            oldstate= state;
            state= getState(app.robotObj);
            x= state(1);
            y= state(2);
            th= state(3);
            
            % Update odometry values
            updateOdom(app.robotObj,oldstate,state)
            
            % If in robot-centric view mode move plot focal point
            if get(app.radio_centric,'Value')
                curr_xlimit= get(app.axes_map,'XLim');
                curr_ylimit= get(app.axes_map,'YLim');
                half_xdist= (curr_xlimit(2)-curr_xlimit(1))/2;
                half_ydist= (curr_ylimit(2)-curr_ylimit(1))/2;
                set(app.axes_map,'XLim',[x-half_xdist x+half_xdist])
                set(app.axes_map,'YLim',[y-half_ydist y+half_ydist])
            end
            
            % Line approximation of the robot
            circ_numPts= 21;    % Estimate circle as circ_numPts-1 lines
            circ_ang=linspace(0,2*pi,circ_numPts);
            circ_rad=ones(1,circ_numPts)*robotRad;
            [circ_x, circ_y]= pol2cart(circ_ang,circ_rad);
            circ_x=circ_x+x;
            circ_y=circ_y+y;
            
            % Update robot circle and direction line     
            set(app.handle_circ,'XData',circ_x)
            set(app.handle_circ,'YData',circ_y)
            set(app.handle_line,'XData',[x x+1.5*robotRad*cos(th)])
            set(app.handle_line,'YData',[y y+1.5*robotRad*sin(th)])
            
            % Update sensors visualization
            updateSensorVisualization(app.robotObj,app,app.handles_sensors)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SimulatorGUI_OpeningFcn(app, varargin)
 
          
            % This function has no output args, see OutputFcn.
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            % UIWAIT makes SimulatorGUI wait for user response (see UIRESUME)
            % uiwait(app.figure1);
           
            % Store robot object for access to functions
            app.robotObj = CreateRobot(app);
            
            % Get constant properties for use in plotting
            [rad, rIR, rSon, rLid, angRLid, ~, rRSDepth, angRSDepth, nRSDepth]= getConstants(app.robotObj);
            
            % Plot robot in default position and store plot handles for updating
            axes(app.axes_map)
          
            circ_numPts= 21;    % Estimate circle as circ_numPts-1 lines
            circ_ang=linspace(0,2*pi,circ_numPts);
            circ_rad=ones(1,circ_numPts)*rad;
            [circ_x, circ_y]= pol2cart(circ_ang,circ_rad);
     
            app.handle_circ= plot(app.axes_map,circ_x,circ_y,'b-','LineWidth',1.5);
            app.handle_line= plot(app.axes_map,[0 1.5*rad],[0 0],'b-','LineWidth',1.5);
             
            
            % Plot initial sensors visualization and make invisible
            % These plot coordinates are rough to reduce computation here, but it is
            %   required that something is plotted
            % GI: can pass app.axes_map in as first argumentwhen
            % refactoring
            handle_wallIR= plot(app.axes_map,[rad rad],[0 -rIR],'r-','LineWidth',2);
            handle_sonarF= plot(app.axes_map,[rad rad+rSon],[0 0],'g-','LineWidth',2);
            handle_sonarL= plot(app.axes_map,[0 0],[rad rad+rSon],'g-','LineWidth',2);
            handle_sonarB= plot(app.axes_map,[-rad -(rad+rSon)],[0 0],'g-','LineWidth',2);
            handle_sonarR= plot(app.axes_map,[0 0],[-rad -(rad+rSon)],'g-','LineWidth',2);
            handle_bumpR= plot(app.axes_map,[0 rad],[-rad 0],'m-','LineWidth',2);
            handle_bumpF= plot(app.axes_map,[rad rad],[-rad/2 rad/2],'m-','LineWidth',2);
            handle_bumpL= plot(app.axes_map,[0 rad],[rad 0],'m-','LineWidth',2);
            handle_cliffR= plot(app.axes_map,0,rad,'c*');
            handle_cliffFR= plot(app.axes_map,rad,-rad/3,'c*');
            handle_cliffFL= plot(app.axes_map,rad,rad/3,'c*');
            handle_cliffL= plot(app.axes_map,0,rad,'c*');
            handle_lidarR= plot(app.axes_map,[rad rLid*cos(-angRLid/2)],...
                [0 rLid*sin(-angRLid/2)],'Color',[1 0.8 0],'LineWidth',2);
            handle_lidarFR= plot(app.axes_map,[rad rLid*cos(-angRLid/4)],...
                [0 rLid*sin(-angRLid/4)],'Color',[1 0.8 0],'LineWidth',2);
            handle_lidarF= plot(app.axes_map,[rad rLid],[0 0],'Color',[1 0.8 0],'LineWidth',2);
            handle_lidarFL= plot(app.axes_map,[rad rLid*cos(angRLid/4)],...
                [0 rLid*sin(angRLid/4)],'Color',[1 0.8 0],'LineWidth',2);
            handle_lidarL= plot(app.axes_map,[rad rLid*cos(angRLid/2)],...
                [0 rLid*sin(angRLid/2)],'Color',[1 0.8 0],'LineWidth',2);
            handle_rsdepth = gobjects(nRSDepth,1);
            for ii = [1:nRSDepth]
                ang = angRSDepth/2 + (ii-1)*(angRSDepth/(nRSDepth-1));
                handle_rsdepth(ii)= plot(app.axes_map,[rad rRSDepth*cos(ang)],...
                    [0 rRSDepth*sin(ang)],'Color',[0.25 0 0.5],'LineWidth',2);
            end
            
            app.handles_sensors= [handle_wallIR handle_sonarF handle_sonarL ...
                handle_sonarB handle_sonarR handle_bumpR handle_bumpF handle_bumpL ...
                handle_cliffR handle_cliffFR handle_cliffFL handle_cliffL ...
                handle_lidarR handle_lidarFR handle_lidarF handle_lidarFL ...
                handle_lidarL handle_rsdepth(:)']';
            
            set(app.handles_sensors,'Visible','off');
          
            
            % Set up timer to control simulation updates
            app.timerSim= timer;
            app.timerSim.BusyMode= 'drop';
            app.timerSim.ExecutionMode= 'fixedSpacing';
            %%%%%%%%%%Change?%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.timerSim.Name= 'CreateSim';    % To specify timer for deletion
            app.timerSim.ObjectVisibility= 'on';
            %%%%%%%%%%Change?%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.timerSim.Period= 0.1;
            app.timerSim.TasksToExecute= inf;
            app.timerSim.TimerFcn= {@app.updateSimInternal}; %updateSim,app}; %<-----CHANGE?
            %start(app.timerSim)
           
        end

        % Value changed function: chkbx_bump
        function chkbx_bump_Callback(app, event)
                      
            % Hint: get(app.chkbx_bump,'Value') returns toggle state of chkbx_bump
            
            % Toggle visibility of sensor visualization with the checkbox
            if app.chkbx_bump.Value
                set(app.handles_sensors(6:8),'Visible','on')
            else
                set(app.handles_sensors(6:8),'Visible','off')
            end
            
            %start the timer if it is off
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            pause(0.15) %gaurd to ensure timer fires at least once
            %stop the timer if the robot is not moving
            state = [app.robotObj.velInt app.robotObj.wInt];
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
            
        end

        % Value changed function: chkbx_cliff
        function chkbx_cliff_Callback(app, event)
            % Hint: get(app.chkbx_cliff,'Value') returns toggle state of chkbx_cliff
            
            % Toggle visibility of sensor visualization with the checkbox        
            if app.chkbx_cliff.Value  
                set(app.handles_sensors(9:12),'Visible','on')
            else
                set(app.handles_sensors(9:12),'Visible','off')
            end
            
            %start the timer if it is off
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            pause(0.15) %gaurd to ensure timer fires at least once
            %stop the timer if the robot is not moving
            state = [app.robotObj.velInt app.robotObj.wInt];
            if isequal(state,[0 0])
                stop(app.timerSim);
            end           
            
        end

        % Value changed function: chkbx_lidar
        function chkbx_lidar_Callback(app, event)
            % Hint: get(app.chkbx_lidar,'Value') returns toggle state of chkbx_lidar
            
            % Toggle visibility of sensor visualization with the checkbox
            if app.chkbx_lidar.Value
                set(app.handles_sensors(13:17),'Visible','on')
            else
                set(app.handles_sensors(13:17),'Visible','off')
            end
            
            %start the timer if it is off
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            pause(0.15) %gaurd to ensure timer fires at least once
            %stop the timer if the robot is not moving
            state = [app.robotObj.velInt app.robotObj.wInt];
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
        end

        % Value changed function: chkbx_rsdepth
        function chkbx_rsdepth_Callback(app, event)
            % Hint: get(app.chkbx_rsdepth,'Value') returns toggle state of chkbx_lidar
            
            % Toggle visibility of sensor visualization with the checkbox
            if app.chkbx_rsdepth.Value
                set(app.handles_sensors(18:26),'Visible','on')
            else
                set(app.handles_sensors(18:26),'Visible','off')
            end
            
            %start the timer if it is off
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            pause(0.15) %gaurd to ensure timer fires at least once
            %stop the timer if the robot is not moving
            state = [app.robotObj.velInt app.robotObj.wInt];
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
        end

        % Value changed function: chkbx_sonar
        function chkbx_sonar_Callback(app, event)
            % Hint: get(app.chkbx_sonar,'Value') returns toggle state of chkbx_sonar
            
            % Toggle visibility of sensor visualization with the checkbox
            if app.chkbx_sonar.Value
                set(app.handles_sensors(2:5),'Visible','on')
            else
                set(app.handles_sensors(2:5),'Visible','off')
            end
            
            %start the timer if it is off
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            pause(0.15) %gaurd to ensure timer fires at least once
            %stop the timer if the robot is not moving
            state = [app.robotObj.velInt app.robotObj.wInt];
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
        end

        % Value changed function: chkbx_wall
        function chkbx_wall_Callback(app, event)
            % Hint: get(app.chkbx_wall,'Value') returns toggle state of chkbx_wall
            
            % Toggle visibility of sensor visualization with the checkbox
            if app.chkbx_wall.Value
                set(app.handles_sensors(1),'Visible','on')
            else
                set(app.handles_sensors(1),'Visible','off')
            end
            
            %start the timer if it is off
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            pause(0.15) %gaurd to ensure timer fires at least once
            %stop the timer if the robot is not moving
            state = [app.robotObj.velInt app.robotObj.wInt];
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
        end

        % Close request function: figure_simulator
        function figure_simulator_CloseRequestFcn(app, event)
            % Delete the timer to prevent further updates
            timerList= timerfindall('Name','CreateSim');
            if ~isempty(timerList)
                stop(timerList)
                delete(timerList)
            end
            
            % Use conditional in case figure is opened without running simulator
            if ~isempty(app)
                % Disable autonomous control
                setAutoEnable(app.robotObj,false)
            end
            
            % Close the figure
            delete(app.figure_simulator);
        end

        % Window key press function: figure_simulator
        function figure_simulator_WindowKeyPressFcn(app, event)

            % Check which key is pressed and call appropriate function
            keydown= event.Key;  % Assume only one key pressed at a time
            if any(strcmp(keydown,{'uparrow' 'w'})) && ...
                    strcmp(get(app.push_fwd,'Enable'),'on')
                %hObject_new= app.push_fwd;
                push_fwd_Callback(app, event)
            elseif any(strcmp(keydown,{'downarrow' 's'})) && ...
                    strcmp(get(app.push_back,'Enable'),'on')
                %hObject_new= app.push_back;
                push_back_Callback(app, event)
            elseif any(strcmp(keydown,{'leftarrow' 'a'})) && ...
                    strcmp(get(app.push_left,'Enable'),'on')
                %hObject_new= app.push_left;
                push_left_Callback(app, event)
            elseif any(strcmp(keydown,{'rightarrow' 'd'})) && ...
                    strcmp(get(app.push_right,'Enable'),'on')
                %hObject_new= app.push_right;
                push_right_Callback(app, event)
            elseif any(strcmp(keydown,{'space' 'return' 'escape'})) && ...
                    strcmp(get(app.push_stop,'Enable'),'on')
                %hObject_new= app.push_stop;
                push_stop_Callback(app, event)
            end
        end

        % Button pushed function: push_auto_start
        function push_auto_start_Callback(app, event)
            %start the timer if it not running already
            if strcmpi(app.timerSim.Running,"off")
                start(app.timerSim)
            end
            
            % Stop robot movement from manual control
            velChange= [NaN NaN];
            manualKeyboard(app.robotObj,velChange)
            
             % Choose file to run as autonomous control function
            [fileName, pathName, filter]= ...
                 uigetfile({'*.m;*.mlx','Code files (*.m,*.mlx)'; ...
                  '*.*',  'All Files (*.*)'},'Load Autonomous Control File','./');
            % uigetfile('*.m','Load Autonomous Control File');
            if fileName
                %fileName= fileName(1:end-2);    % Take off '.m' extension
                [filepath,fileName,ext]=fileparts(fileName);
                if ~strcmp(cd,pathName) % If control program isn't in current directory
                    addpath(pathName)   % Add the path to it to allow access
                end	% This will not save over to future sessions of MATLAB unless the user
                % has the setting to save the current search path upon exit
            
                % Set autonomous control of robot to be enabled
                setAutoEnable(app.robotObj,true)
            
                % Start timer to determine time steps for data output and add first row
                startTimeElap(app.robotObj)
                updateOutput(app.robotObj)
            
                % Disable manual control buttons
                set(app.push_map,'Enable','off')
                set(app.push_config,'Enable','off')
                set(app.push_origin,'Enable','off')
                set(app.push_fwd,'Enable','off')
                set(app.push_back,'Enable','off')
                set(app.push_left,'Enable','off')
                set(app.push_right,'Enable','off')
                set(app.push_stop,'Enable','off')
                set(app.push_auto_start,'Enable','off')
            
                % Call odometry reading functions to zero readings
                genOdomAng(app.robotObj);
                genOdomDist(app.robotObj);
            
                % Catch error thrown when autonomous control is disabled and control
                % program calls a translator function, in order to halt control program
                try
                    % Run autonomous controller function
                    feval(fileName,app.robotObj);
            
                    % Run the Stop button callback to save data and re-enable manual
                    % This will only run if Stop is not pushed during autonomous
                    %hObject_new= app.push_auto_stop;  %GI CHECK THIS
                    push_auto_stop_Callback(app, event) %hObject_new)
                catch me                    % Catch error and information
                    if strcmp(me.identifier,'SIMULATOR:AutonomousDisabled')
                        manualKeyboard(app.robotObj,[NaN NaN])   % Stop robot movement
                        return                          % Allow GUI control again
                    else                % Not error from control being disabled
                        rethrow(me)     % Allow error to propagate to command window
                    end
                end
            end
            %stop the timer after execution of the function
            state = [app.robotObj.velInt app.robotObj.wInt];
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
        end

        % Button pushed function: push_auto_stop
        function push_auto_stop_Callback(app, event)
            % Set autonomous control to be disabled
            setAutoEnable(app.robotObj,false)
            manualKeyboard(app.robotObj,[NaN NaN])
            
            % Export and reset the output data
            if app.chkbx_auto_save.Value
                saveOutput(app.robotObj)
            end
            resetOutput(app.robotObj)
            
            % Enable manual control buttons
            set(app.push_map,'Enable','on')
            set(app.push_config,'Enable','on')
            set(app.push_origin,'Enable','on')
            set(app.push_fwd,'Enable','on')
            set(app.push_back,'Enable','on')
            set(app.push_left,'Enable','on')
            set(app.push_right,'Enable','on')
            set(app.push_stop,'Enable','on')
            set(app.push_auto_start,'Enable','on')
            
            %stop the timer
            stop(app.timerSim);
        end

        % Button pushed function: push_back
        function push_back_Callback(app, event)
            %start the timer if it is not running
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            
            velChange= [-0.1 0];
            manualKeyboard(app.robotObj,velChange)
            
            state = [app.robotObj.velInt app.robotObj.wInt];
            %stop the timer if changing the velocities set them 0
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
        end

        % Button pushed function: push_config
        function push_config_Callback(app, event)

            % Import configuration file
            [filename, pathname, filter]= uigetfile('*.txt','Import Configuration File');
            noise= struct;  % Initialize empty structure for noise information
            if filename                         % Make sure cancel was not pressed
                fid= fopen([pathname, filename]);% Get file handle for parsing
            
                % Parse the file and extract relevant information
                while ~feof(fid)
                    fline= fgetl(fid);      % Get line of file
                    line= lower(fline);     % Convert to lowercase if necessary
                    line= strtrim(line);    % Delete leading and trailing whitespace
                    lineWords= {};          % To keep track of line entries
                    while ~isempty(line) && ~strcmp(line(1),'%')% End of line or comment
                        [word, line]= strtok(line);  % Get next entry
                        line= strtrim(line);        % To be able to detect comments
                        lineWords= [lineWords word];
                    end
            
                    % Get information from line
                    sensors= {'wall' 'cliff' 'odometry' 'sonar' 'lidar' 'camera' 'imu' 'rsdepth'};
                    if length(lineWords) == 2 && strcmp(lineWords{1},'com_delay')
                        setComDelay(app.robotObj,str2double(lineWords{2}))
                    elseif length(lineWords) == 3 && any(strcmp(lineWords{1},sensors))
                        % Name the field in the noise structure after the sensor and
                        % store the mean and standard deviation of the noise in it
                        noise.(lineWords{1})= ...
                            [str2double(lineWords{2}) str2double(lineWords{3})];
                    elseif ~isempty(lineWords)
                        warning('MATLAB:invalidInput',...
                            'This line in config file %s is unrecognized:\n\t%s',...
                            filename,fline)
                    end
                end
                fclose(fid);
            
                % Set noise data in robot object
                setNoise(app.robotObj,noise)
            end
        end

        % Button pushed function: push_fwd
        function push_fwd_Callback(app, event)
            %start the timer if it is not running
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            
            velChange= [0.1 0];
            manualKeyboard(app.robotObj,velChange)
            
            state = [app.robotObj.velInt app.robotObj.wInt];
            %stop the timer if changing the velocities set them 0
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
        end

        % Button pushed function: push_left
        function push_left_Callback(app, event)
            %start the timer if it is not running
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            
            % Increase angular speed in robot object
            velChange= [0 0.5];
            manualKeyboard(app.robotObj,velChange)
            
            state = [app.robotObj.velInt app.robotObj.wInt];
            %stop the timer if changing the velocities set them 0
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
        end

        % Button pushed function: push_map
        function push_map_Callback(app, event)
       
            % Import map file
            [filename, pathname, filter]= uigetfile('*.txt','Import Map File');
            if filename                         % Make sure cancel was not pressed
                fid= fopen([pathname filename]);% Get file handle for parsing
            
                % Parse the file and extract relevant information
                walls= [];
                lines= [];
                beacs= {};
                vwalls= [];
                while ~feof(fid)
                    fline= fgetl(fid);
                    line= lower(fline);     % Convert to lowercase if necessary
                    line= strtrim(line);    % Delete leading and trailing whitespace
                    lineWords= {};          % To keep track of line entries
                    while ~isempty(line) && ~strcmp(line(1),'%')% End of line or comment
                        [word,line]= strtok(line);  % Get next entry
                        line= strtrim(line);        % To be able to detect comments
                        lineWords= [lineWords word];
                    end
            
                    %%% Regular expressions would probably be more efficient %%%
                    if length(lineWords) == 5 && strcmp(lineWords{1},'wall') && ...
                            ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            ~isnan(str2double(lineWords{4})) && ...
                            ~isnan(str2double(lineWords{5}))
                        walls= [walls ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) str2double(lineWords{4}) ...
                            str2double(lineWords{5})];
                    elseif length(lineWords) == 5 && strcmp(lineWords{1},'line') && ...
                            ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            ~isnan(str2double(lineWords{4})) && ...
                            ~isnan(str2double(lineWords{5}))
                        lines= [lines ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) str2double(lineWords{4}) ...
                            str2double(lineWords{5})];
                    elseif length(lineWords) == 7 && strcmp(lineWords{1},'beacon') ...
                            && ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            length(lineWords{4}) >= 2 && ...
                            strcmp(lineWords{4}(1),'[') && ...
                            ~isnan(str2double(lineWords{4}(2:end))) && ...
                            ~isnan(str2double(lineWords{5})) && ...
                            length(lineWords{6}) >= 2 && ...
                            strcmp(lineWords{6}(end),']') && ...
                            ~isnan(str2double(lineWords{6}(1:end-1)))
                        beacs= [beacs ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) ...
                            str2double(lineWords{4}(2:end)) ...
                            str2double(lineWords{5}) ...
                            str2double(lineWords{6}(1:end-1)) lineWords(7)];
                    elseif length(lineWords) == 5 && ...
                            strcmp(lineWords{1},'virtwall') && ...
                            ~isnan(str2double(lineWords{2})) && ...
                            ~isnan(str2double(lineWords{3})) && ...
                            ~isnan(str2double(lineWords{4})) && ...
                            ~isnan(str2double(lineWords{5})) && ...
                            str2double(lineWords{5}) >= 1 && ...
                            str2double(lineWords{5}) <= 3
                        vwalls= [vwalls ; str2double(lineWords{2}) ...
                            str2double(lineWords{3}) str2double(lineWords{4}) ...
                            str2double(lineWords{5})];
                    elseif ~isempty(lineWords)
                        warning('MATLAB:invalidInput',...
                            'This line in map file %s is unrecognized:\n\t%s',...
                            filename,fline)
                    end
                end
                fclose(fid);
            
                % Set map data in robot object
                setMap(app.robotObj,walls,lines,beacs,vwalls)
            
                % Clear old map
                axes(app.axes_map)
                children= get(gca,'Children');  % All ploted items on axes
                
                for i= 1:length(app.handles_sensors)    % Keep sensors visualization
                    children(find(app.handles_sensors(i) == children))= [];
                end
                if ~isempty(children)
                    delete(children(1:end-2))	% Delete all but robot from axes
                end
            
                % Plot walls
                for i= 1:size(walls,1)
                    plot(app.axes_map,walls(i,[1 3]),walls(i,[2 4]),'k-','LineWidth',1)
                end
            
                % Plot lines
                for i= 1:size(lines,1)
                    plot(app.axes_map,lines(i,[1 3]),lines(i,[2 4]),'k--','LineWidth',1)
                end
            
                % Plot beacons
                for i= 1:size(beacs,1)
                    plot(app.axes_map,beacs{i,1},beacs{i,2},...
                        'Color',cell2mat(beacs(i,3:5)),'Marker','o')
                    text(app.axes_map,beacs{i,1},beacs{i,2},['  ' beacs{i,6}])
                end
            
                % Plot virtual walls
                % Define virtual wall emitter constants
                halo_rad= 0.45;     % Radius of the halo around the emitter
                range_short= 2.13;  % Range of the wall on the 0-3' setting
                ang_short= 0.33;    % Angular range on the 0-3' setting
                range_med= 5.56;    % Range of the wall on the 4'-7' setting
                ang_med= 0.49;      % Angular range on the 4'-7' setting
                range_long= 8.08;   % Range of the wall on the 8'+ setting
                ang_long= 0.61;     % Angular range on the 8'+ setting
            
                % Get points to map virtual walls
                for i= 1:size(vwalls,1)
                    x_vw= vwalls(i,1);
                    y_vw= vwalls(i,2);
                    th_vw= vwalls(i,3);
                    if vwalls(i,4) == 1
                        range_vw= range_short;
                        ang_vw= ang_short;
                    elseif vwalls(i,4) == 2
                        range_vw= range_med;
                        ang_vw= ang_med;
                    else
                        range_vw= range_long;
                        ang_vw= ang_long;
                    end
                    x_1= x_vw+range_vw*cos(th_vw+ang_vw/2);
                    y_1= y_vw+range_vw*sin(th_vw+ang_vw/2);
                    x_2= x_vw+range_vw*cos(th_vw-ang_vw/2);
                    y_2= y_vw+range_vw*sin(th_vw-ang_vw/2);
            
                    % Plot halo around emitter and range triangle
                    circ_numPts= 21;    % Estimate circle as circ_numPts-1 lines
                    circ_ang=linspace(0,2*pi,circ_numPts);
                    circ_rad=ones(1,circ_numPts)*halo_rad;
                    [circ_x, circ_y]= pol2cart(circ_ang,circ_rad);
                    circ_x= circ_x+x_vw;
                    circ_y= circ_y+y_vw;
                    plot(app.axes_map,x_vw,y_vw,'g*')
                    plot(app.axes_map,circ_x,circ_y,'g:','LineWidth',1);
                    plot(app.axes_map,[x_vw x_1 x_2 x_vw],[y_vw y_1 y_2 y_vw],'g:','LineWidth',1)
                end
            end
        end

        % Button pushed function: push_origin
        function push_origin_Callback(app, event)
                       
            % Set origin data in robot object
            [x,y]= ginput(2);   % Accept two mouse clicks
            th= atan2(y(2)-y(1),x(2)-x(1)); % Direction is set by second mouse click
            origin= [x(1) y(1) th];         % Start point is set by first mouse click
            setMapStart(app.robotObj,origin)
           
            %start the timer if it not already running
            if strcmpi(app.timerSim.Running,"off")
                start(app.timerSim)
            end
            pause(0.15)
            
            %stop the timer after repositioning the robot if the velocity
            %is 0
            state = [app.robotObj.velInt app.robotObj.wInt];
            if isequal(state,[0 0])
                stop(app.timerSim)
            end
            
        end

        % Button pushed function: push_right
        function push_right_Callback(app, event)
            %start the timer if it is not running
            if strcmpi(app.timerSim.Running,'off')
                start(app.timerSim);
            end
            
            % Decrease angular speed in robot object
            velChange= [0 -0.5];
            manualKeyboard(app.robotObj,velChange)
            
            state = [app.robotObj.velInt app.robotObj.wInt];
            %stop the timer if changing the velocities set them 0
            if isequal(state,[0 0])
                stop(app.timerSim);
            end
        end

        % Button pushed function: push_sensors
        function push_sensors_Callback(app, event)

            % Get robot object to query sensors and output to command window
            [rad,rIR,rSon,rLid,angRLid,numPtsLid,rRSDepth,angRSDepth,nRSDepth]= getConstants(app.robotObj);
            
            assignin('base','robotObj',app.robotObj); % Assign robotObj to workspace
            % Bump sensors
            bump= genBump(app.robotObj);
            bumpR= bump(1);
            bumpF= bump(2);
            bumpL= bump(3);
            fprintf('Bump Sensors:\n')
            fprintf('\tRight: %.0f\n',bumpR)
            fprintf('\tFront: %.0f\n',bumpF)
            fprintf('\tLeft: %.0f\n\n',bumpL)
            
            % Cliff sensors
            cliffR= 0;
            cliffFR= 0;
            cliffFL= 0;
            cliffL= 0;
            cliff= genCliff(app.robotObj);
            cliffRstr= cliff(1);
            cliffFRstr= cliff(2);
            cliffFLstr= cliff(3);
            cliffLstr= cliff(4);
            fprintf('Cliff Sensors:\n')
            fprintf('\tRight: State %.0f Strength %%%.3f\n',cliffR,cliffRstr)
            fprintf('\tFront-Right: State %.0f Strength %%%.3f\n',cliffFR,cliffFRstr)
            fprintf('\tFront-Left: State %.0f Strength %%%.3f\n',cliffFL,cliffFLstr)
            fprintf('\tLeft: State %.0f Strength %%%.3f\n\n',cliffL,cliffLstr)
            
            % Infrared wall sensor
            wall= genIR(app.robotObj);
            fprintf('Wall Sensor: %.0f\n\n',wall)
            
            % Virtual Wall sensor
            vwall= genVWall(app.robotObj);
            fprintf('Virtual Wall Sensor: %.0f\n\n',vwall)
            
            % Sonar sensors
            distSonar= genSonar(app.robotObj);
            fprintf('Sonar Sensors:\n')
            fprintf('\tFront: %.3f m\n',distSonar(1))
            fprintf('\tLeft: %.3f m\n',distSonar(2))
            fprintf('\tRear: %.3f m\n',distSonar(3))
            fprintf('\tRight: %.3f m\n\n',distSonar(4))
            
            % LIDAR sensor
            handleGUI= gcf; % Get GUI figure handle to switch back to
            distLidar= genLidar(app.robotObj);
            angleData= linspace(-angRLid/2, angRLid/2,numPtsLid); %[AA - not sure why this was offset by pi/2 before]
            assignin('base','lidar',[angleData',distLidar']); % Added Line
            figure
            hold on
            polar(linspace(0,2*pi,11),rad*ones(1,11),'b-')
            polar([0 0],[0 1.5*rad],'b-') % [AA- This was plotting incorrectly orientation (in robot coordinate frame) should be 0]
            polar([angleData(1) 0 angleData(end)],[distLidar(1) 0 distLidar(end)],'k-')
            polar(angleData,distLidar,'k.')
            axis([-10 10 -10 10])
            title('LIDAR Data')
            xlabel('Distance (m)')
            ylabel('Distance (m)')
            hold off
            figure(handleGUI) % Switch back to GUI figure
            fprintf('LIDAR Sensor: see figure\n\n')

            
            % Odometry
            distOdom= genOdomDist(app.robotObj);
            angOdom= genOdomAng(app.robotObj);
            fprintf('Odometry Data (since last call):\n')
            fprintf('\tDistance: %.3f m\n',distOdom)
            fprintf('\tAngle: %.3f m\n\n',angOdom)
            
            % Overhead localization system
            [x, y, th]= genOverhead(app.robotObj);
            assignin('base','robot_pose',[x y th]); % Added Line
            fprintf('Overhead Localization System output:\n')
            fprintf('\tX-Coordinate: %.3f m\n',x)
            fprintf('\tY-Coordinate: %.3f m\n',y)
            fprintf('\tAngle relative to horizontal: %.3f rad\n\n',th)
            
            % Camera
            [angCameraBeacon, distCameraBeacon, colorCam, ID]= genCamera(app.robotObj);
            % Also see ReadBeacon function in CreateRobot.m for details on camera.
            
            X = distCameraBeacon.*cos(angCameraBeacon);    % Camera x-axis points forward (depth)
            Y = distCameraBeacon.*sin(angCameraBeacon);    % Camera's positive y-axis points left
            fprintf('Camera Data:\n')
            for i= 1:length(angCameraBeacon)
                fprintf('Beacon at: theta = %.0f deg, R = %.3f m, with color [%.2f %.2f %.2f] \n',...
                    rad2deg(angCameraBeacon(i)),distCameraBeacon(i),colorCam(i,1),colorCam(i,2),...
                    colorCam(i,3))
                fprintf('           X = %.2f m, Y = %.2f m, Z = %.2f m, rot = %.0f deg, with tag number %.0f \n',...
                    X(i),Y(i),0,0,ID(i))
            end
            fprintf('\n')
            
            % RS Depth
            rsDepth= genRSDepth(app.robotObj);
            anglesRSDepth = linspace(angRSDepth/2,-angRSDepth/2,nRSDepth);
            for ii = 1:nRSDepth
                fprintf('Depth from sensor at %.3f deg: %.3f m\n',rad2deg(anglesRSDepth(ii)),rsDepth(ii));
            end
            
            fprintf('\n')
        end

        % Button pushed function: push_stop
        function push_stop_Callback(app, event)
            % Zero all velocity in robot object
            velChange= [NaN NaN];
            manualKeyboard(app.robotObj,velChange)
            
            %stop the timer if it is running
            if strcmpi(app.timerSim.Running,'on')
                stop(app.timerSim);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_simulator = uifigure('Visible', 'off');
            app.figure_simulator.Name = 'SimulatorGUI';
            app.figure_simulator.CloseRequestFcn = createCallbackFcn(app, @figure_simulator_CloseRequestFcn, true);
            app.figure_simulator.WindowKeyPressFcn = createCallbackFcn(app, @figure_simulator_WindowKeyPressFcn, true);
            app.figure_simulator.Tag = 'figure_simulator';
            app.axes_map = uiaxes(app.figure_simulator);
            app.axes_map.XLim = [-5 5];
            app.axes_map.YLim = [-5 5];
            app.axes_map.Tag = 'axes_map';
            app.text_title = uilabel(app.figure_simulator);
            app.text_title.Tag = 'text_title';
            app.text_title.Text = 'Create Simulator';
            app.uipanel1 = uibuttongroup(app.figure_simulator);
            app.uipanel1.Title = 'Sensors';
            app.uipanel1.Tag = 'uipanel1';
            app.push_sensors = uibutton(app.uipanel1, 'push');
            app.push_sensors.ButtonPushedFcn = createCallbackFcn(app, @push_sensors_Callback, true);
            app.push_sensors.Tag = 'push_sensors';
            app.push_sensors.Text = 'Read Sensors';
            app.chkbx_wall = uicheckbox(app.uipanel1);
            app.chkbx_wall.ValueChangedFcn = createCallbackFcn(app, @chkbx_wall_Callback, true);
            app.chkbx_wall.Tag = 'chkbx_wall';
            app.chkbx_wall.Text = 'Wall (IR)';
            app.chkbx_sonar = uicheckbox(app.uipanel1);
            app.chkbx_sonar.ValueChangedFcn = createCallbackFcn(app, @chkbx_sonar_Callback, true);
            app.chkbx_sonar.Tag = 'chkbx_sonar';
            app.chkbx_sonar.Text = 'Sonar';
            app.chkbx_bump = uicheckbox(app.uipanel1);
            app.chkbx_bump.ValueChangedFcn = createCallbackFcn(app, @chkbx_bump_Callback, true);
            app.chkbx_bump.Tag = 'chkbx_bump';
            app.chkbx_bump.Text = 'Bump';
            app.chkbx_cliff = uicheckbox(app.uipanel1);
            app.chkbx_cliff.ValueChangedFcn = createCallbackFcn(app, @chkbx_cliff_Callback, true);
            app.chkbx_cliff.Tag = 'chkbx_cliff';
            app.chkbx_cliff.Text = 'Cliff';
            app.chkbx_lidar = uicheckbox(app.uipanel1);
            app.chkbx_lidar.ValueChangedFcn = createCallbackFcn(app, @chkbx_lidar_Callback, true);
            app.chkbx_lidar.Tag = 'chkbx_lidar';
            app.chkbx_lidar.Text = 'LIDAR';
            app.chkbx_rsdepth = uicheckbox(app.uipanel1);
            app.chkbx_rsdepth.ValueChangedFcn = createCallbackFcn(app, @chkbx_rsdepth_Callback, true);
            app.chkbx_rsdepth.Tag = 'chkbx_rsdepth';
            app.chkbx_rsdepth.Text = 'RSDepth';
            app.uipanel2 = uibuttongroup(app.figure_simulator);
            app.uipanel2.Title = 'Manual Control';
            app.uipanel2.Tag = 'uipanel2';
            app.push_right = uibutton(app.uipanel2, 'push');
            app.push_right.ButtonPushedFcn = createCallbackFcn(app, @push_right_Callback, true);
            app.push_right.Tag = 'push_right';
            app.push_right.Text = '';
            app.push_back = uibutton(app.uipanel2, 'push');
            app.push_back.ButtonPushedFcn = createCallbackFcn(app, @push_back_Callback, true);
            app.push_back.Tag = 'push_back';
            app.push_back.Text = '';
            app.push_left = uibutton(app.uipanel2, 'push');
            app.push_left.ButtonPushedFcn = createCallbackFcn(app, @push_left_Callback, true);
            app.push_left.Tag = 'push_left';
            app.push_left.Text = '';
            app.push_stop = uibutton(app.uipanel2, 'push');
            app.push_stop.ButtonPushedFcn = createCallbackFcn(app, @push_stop_Callback, true);
            app.push_stop.Tag = 'push_stop';
            app.push_stop.Text = 'STOP';
            app.push_fwd = uibutton(app.uipanel2, 'push');
            app.push_fwd.ButtonPushedFcn = createCallbackFcn(app, @push_fwd_Callback, true);
            app.push_fwd.Tag = 'push_fwd';
            app.push_fwd.Text = '';
            app.push_map = uibutton(app.figure_simulator, 'push');
            app.push_map.ButtonPushedFcn = createCallbackFcn(app, @push_map_Callback, true);
            app.push_map.Tag = 'push_map';
            app.push_map.Text = 'Load Map';
            app.push_config = uibutton(app.figure_simulator, 'push');
            app.push_config.ButtonPushedFcn = createCallbackFcn(app, @push_config_Callback, true);
            app.push_config.Tag = 'push_config';
            app.push_config.Text = 'Load Config';
            app.push_origin = uibutton(app.figure_simulator, 'push');
            app.push_origin.ButtonPushedFcn = createCallbackFcn(app, @push_origin_Callback, true);
            app.push_origin.Tag = 'push_origin';
            app.push_origin.Text = 'Set Position';
            app.text7 = uilabel(app.figure_simulator);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Click center then direction';
            app.uipanel3 = uibuttongroup(app.figure_simulator);
            app.uipanel3.Title = 'Camera Position';
            app.uipanel3.Tag = 'uipanel3';
            app.radio_global = uiradiobutton(app.uipanel3);
            app.radio_global.Tag = 'radio_global';
            app.radio_global.Text = 'Global';
            app.radio_global.Value = true;
            app.radio_centric = uiradiobutton(app.uipanel3);
            app.radio_centric.Tag = 'radio_centric';
            app.radio_centric.Text = 'Robot Centric';
            app.uipanel_auto = uibuttongroup(app.figure_simulator);
            app.uipanel_auto.Title = 'Autonomous';
            app.uipanel_auto.Tag = 'uipanel_auto';
            app.push_auto_start = uibutton(app.uipanel_auto, 'push');
            app.push_auto_start.ButtonPushedFcn = createCallbackFcn(app, @push_auto_start_Callback, true);
            app.push_auto_start.Tag = 'push_auto_start';
            app.push_auto_start.Text = 'Start';
            app.push_auto_stop = uibutton(app.uipanel_auto, 'push');
            app.push_auto_stop.ButtonPushedFcn = createCallbackFcn(app, @push_auto_stop_Callback, true);
            app.push_auto_stop.Tag = 'push_auto_stop';
            app.push_auto_stop.Text = 'Stop';
            app.chkbx_auto_save = uicheckbox(app.uipanel_auto);
            app.chkbx_auto_save.Tag = 'chkbx_auto_save';
            app.chkbx_auto_save.Text = 'Save Data';
            app.uipanel5 = uipanel(app.figure_simulator);
            app.uipanel5.Title = 'Create Buttons';
            app.uipanel5.Tag = 'uipanel5';
            app.toggle_play = uibutton(app.uipanel5, 'state');
            app.toggle_play.Tag = 'toggle_play';
            app.toggle_play.Tooltip = 'Play';
            app.toggle_play.Text = '>';
            app.toggle_adv = uibutton(app.uipanel5, 'state');
            app.toggle_adv.Tag = 'toggle_adv';
            app.toggle_adv.Tooltip = 'Advance';
            app.toggle_adv.Text = '>>|';
            app.text_power = uilabel(app.uipanel5);
            app.text_power.Tag = 'text_power';
            app.text_power.Tooltip = 'Power LED';
            app.text_power.Text = '    ';
            app.text_play = uilabel(app.uipanel5);
            app.text_play.Tag = 'text_play';
            app.text_play.Tooltip = 'Play LED';
            app.text_play.Text = '    ';
            app.text_adv = uilabel(app.uipanel5);
            app.text_adv.Tag = 'text_adv';
            app.text_adv.Tooltip = 'Advance LED';
            app.text_adv.Text = '    ';
            app.uipanel6 = uibuttongroup(app.figure_simulator);
            app.uipanel6.Title = 'Sim Speed';
            app.uipanel6.Tag = 'uipanel6';
            app.radio_speed1 = uiradiobutton(app.uipanel6);
            app.radio_speed1.Tag = 'radio_speed1';
            app.radio_speed1.Text = '1x';
            app.radio_speed1.Value = true;
            app.radio_speed3 = uiradiobutton(app.uipanel6);
            app.radio_speed3.Tag = 'radio_speed3';
            app.radio_speed3.Text = '3x';
            app.radio_speed2 = uiradiobutton(app.uipanel6);
            app.radio_speed2.Tag = 'radio_speed2';
            app.radio_speed2.Text = '2x';
            % Show the figure after all components are created
            app.figure_simulator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulatorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure_simulator)

                % Execute the startup function
                runStartupFcn(app, @(app)SimulatorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure_simulator)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_simulator)
        end
    end
end